classdef Analysis_Plot_alert < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ThedestinationalreadycontainsfiguresforLabel  matlab.ui.control.Label
        ReplaceButton                  matlab.ui.control.Button
        UseitButton                    matlab.ui.control.Button
        Alert_mfrLabel                 matlab.ui.control.Label
        Alert_lvrLabel                 matlab.ui.control.Label
        Alert_bootLabel                matlab.ui.control.Label
        Alert_pcLabel                  matlab.ui.control.Label
        Alert_tsLabel                  matlab.ui.control.Label
        Duration_WindowEditFieldLabel  matlab.ui.control.Label
        Duration_WindowEditField       matlab.ui.control.EditField
        StartfolderEditFieldLabel      matlab.ui.control.Label
        StartfolderEditField           matlab.ui.control.EditField
        ResultFolderEditFieldLabel     matlab.ui.control.Label
        ResultFolderEditField          matlab.ui.control.EditField
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        bootEditFieldLabel             matlab.ui.control.Label
        bootEditField                  matlab.ui.control.NumericEditField
        bin_sizeEditFieldLabel         matlab.ui.control.Label
        bin_sizeEditField              matlab.ui.control.NumericEditField
        MFREditFieldLabel              matlab.ui.control.Label
        MFREditField                   matlab.ui.control.NumericEditField
        NumofIterationsEditFieldLabel  matlab.ui.control.Label
        NumofIterationsEditField       matlab.ui.control.NumericEditField
        binEditFieldLabel              matlab.ui.control.Label
        binEditField                   matlab.ui.control.NumericEditField
        pcEditFieldLabel               matlab.ui.control.Label
        pcEditField                    matlab.ui.control.NumericEditField
        lvrEditFieldLabel              matlab.ui.control.Label
        lvrEditField                   matlab.ui.control.NumericEditField
        tsEditFieldLabel               matlab.ui.control.Label
        tsEditField                    matlab.ui.control.NumericEditField
        RasterEditFieldLabel           matlab.ui.control.Label
        RasterEditField                matlab.ui.control.NumericEditField
        RawEditFieldLabel              matlab.ui.control.Label
        RawEditField                   matlab.ui.control.NumericEditField
        CancelButton                   matlab.ui.control.Button
        mfrEditFieldLabel              matlab.ui.control.Label
        mfrEditField                   matlab.ui.control.NumericEditField
        LVRLabel                       matlab.ui.control.Label
        LVREditField                   matlab.ui.control.NumericEditField
        BOOTLabel                      matlab.ui.control.Label
        BOOTEditField                  matlab.ui.control.NumericEditField
        PCLabel                        matlab.ui.control.Label
        PCEditField                    matlab.ui.control.NumericEditField
        YESEditFieldLabel              matlab.ui.control.Label
        YESEditField                   matlab.ui.control.NumericEditField
        RawEditField_2Label            matlab.ui.control.Label
        SpkEditField                   matlab.ui.control.NumericEditField
        YESEditField_2Label            matlab.ui.control.Label
        ChannelsEditField              matlab.ui.control.NumericEditField
        Alert_Spk                      matlab.ui.control.Label
        mfrEditField_2Label            matlab.ui.control.Label
        spkEditField                   matlab.ui.control.NumericEditField
        PTSDEditFieldLabel             matlab.ui.control.Label
        PTSDEditField                  matlab.ui.control.NumericEditField
        SWTTEOEditFieldLabel           matlab.ui.control.Label
        SWTTEOEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Plot_panel, Alert_spk, Allert_mfr, Allert_lvr, Allert_boot, Allert_pc, Allert_ts, Startfolder, ResultFolder, Experiment, bin_size, bin, NumofIterations, Duration_Window, MFR, LVR, BOOT, PC, ts, raster, raw, SPK, spk, mfr, lvr, boot, pc, YES, PTSD, SWTTEO, CH)
            % Store main app object
            app.CallingApp = Plot_panel;
            app.ExperimentEditField.Value = Experiment;
            app.StartfolderEditField.Value = Startfolder;
            app.ResultFolderEditField.Value = ResultFolder;
            app.bin_sizeEditField.Value = bin_size;
            app.binEditField.Value = bin;
            app.NumofIterationsEditField.Value = NumofIterations;
            app.Duration_WindowEditField.Value = Duration_Window;
            app.MFREditField.Value = MFR;
            app.mfrEditField.Value = mfr;
            app.LVREditField.Value = LVR;
            app.lvrEditField.Value = lvr;
            app.BOOTEditField.Value = BOOT;
            app.bootEditField.Value = boot;
            app.PCEditField.Value = PC;
            app.pcEditField.Value = pc;
            app.tsEditField.Value = ts;
            app.RasterEditField.Value = raster;
            app.RawEditField.Value = raw;
            app.SpkEditField.Value = SPK;
            app.spkEditField.Value = spk;
            app.YESEditField.Value = YES;
            app.PTSDEditField.Value = PTSD;
            app.SWTTEOEditField.Value = SWTTEO;
            app.ChannelsEditField.Value = CH;
            app.Alert_Spk.Text = Alert_spk;
            app.Alert_mfrLabel.Text = Allert_mfr;
            app.Alert_lvrLabel.Text = Allert_lvr;
            app.Alert_bootLabel.Text = Allert_boot;
            app.Alert_pcLabel.Text = Allert_pc;
            app.Alert_tsLabel.Text = Allert_ts;
            
            if app.Alert_Spk.Text == 'K'
                app.Alert_Spk.Text = 'Spikes_plot';
                app.Alert_Spk.Visible = true;
            end
            if app.Alert_mfrLabel.Text == 'F'
                app.Alert_mfrLabel.Text = 'Mfr';
                app.Alert_mfrLabel.Visible = true;
            end
            if app.Alert_lvrLabel.Text == 'V'
                app.Alert_lvrLabel.Text = 'Lvr';
                app.Alert_lvrLabel.Visible = true;
            end
            if app.Alert_bootLabel.Text == 'O'
                app.Alert_bootLabel.Text = 'BootstrapFR (boot)';
                app.Alert_bootLabel.Visible = true;
            end
            if app.Alert_pcLabel.Text == 'C'
                app.Alert_pcLabel.Text = 'Population Coupling (PC)';
                app.Alert_pcLabel.Visible = true;
            end
            if app.Alert_tsLabel.Text == 'S'
                app.Alert_tsLabel.Text = 'TS';
                app.Alert_tsLabel.Visible = true;
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            app.ReplaceButton.Enable = 'off';
            app.UseitButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizeEditField.Value,app.binEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowEditField.Value,app.MFREditField.Value,app.LVREditField.Value,app.BOOTEditField.Value,app.PCEditField.Value,app.tsEditField.Value,app.RasterEditField.Value,app.RawEditField.Value,app.SpkEditField.Value,app.spkEditField.Value,app.mfrEditField.Value,app.lvrEditField.Value,app.bootEditField.Value,app.pcEditField.Value,app.YESEditField.Value,app.PTSDEditField.Value,app.SWTTEOEditField.Value,app.ChannelsEditField.Value);
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: UseitButton
        function UseitButtonPushed(app, event)
            app.ReplaceButton.Enable = 'off';
            app.UseitButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            Main_Analysis_Plot(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizeEditField.Value,app.binEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowEditField.Value,app.spkEditField,app.MFREditField.Value,app.LVREditField.Value,app.BOOTEditField.Value,app.PCEditField.Value,app.YESEditField.Value,app.PTSDEditField.Value,app.SWTTEOEditField.Value,app.ChannelsEditField.Value);
            delete(app)
        end

        % Value changed function: YESEditField
        function YESEditFieldValueChanged(app, event)
            value = app.YESEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThedestinationalreadycontainsfiguresforLabel = uilabel(app.UIFigure);
            app.ThedestinationalreadycontainsfiguresforLabel.Text = 'The destination already contains figures for: ';
            app.ReplaceButton = uibutton(app.UIFigure, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.UseitButton = uibutton(app.UIFigure, 'push');
            app.UseitButton.ButtonPushedFcn = createCallbackFcn(app, @UseitButtonPushed, true);
            app.UseitButton.Text = 'Use it';
            app.Alert_mfrLabel = uilabel(app.UIFigure);
            app.Alert_mfrLabel.Visible = 'off';
            app.Alert_mfrLabel.Text = 'Alert_mfr';
            app.Alert_lvrLabel = uilabel(app.UIFigure);
            app.Alert_lvrLabel.Visible = 'off';
            app.Alert_lvrLabel.Text = 'Alert_lvr';
            app.Alert_bootLabel = uilabel(app.UIFigure);
            app.Alert_bootLabel.Visible = 'off';
            app.Alert_bootLabel.Text = 'Alert_boot';
            app.Alert_pcLabel = uilabel(app.UIFigure);
            app.Alert_pcLabel.Visible = 'off';
            app.Alert_pcLabel.Text = 'Alert_pc';
            app.Alert_tsLabel = uilabel(app.UIFigure);
            app.Alert_tsLabel.Visible = 'off';
            app.Alert_tsLabel.Text = 'Alert_ts';
            app.Duration_WindowEditFieldLabel = uilabel(app.UIFigure);
            app.Duration_WindowEditFieldLabel.Enable = 'off';
            app.Duration_WindowEditFieldLabel.Visible = 'off';
            app.Duration_WindowEditFieldLabel.Text = 'Duration_Window';
            app.Duration_WindowEditField = uieditfield(app.UIFigure, 'text');
            app.Duration_WindowEditField.Editable = 'off';
            app.Duration_WindowEditField.Enable = 'off';
            app.Duration_WindowEditField.Visible = 'off';
            app.StartfolderEditFieldLabel = uilabel(app.UIFigure);
            app.StartfolderEditFieldLabel.Enable = 'off';
            app.StartfolderEditFieldLabel.Visible = 'off';
            app.StartfolderEditFieldLabel.Text = 'Startfolder';
            app.StartfolderEditField = uieditfield(app.UIFigure, 'text');
            app.StartfolderEditField.Editable = 'off';
            app.StartfolderEditField.Enable = 'off';
            app.StartfolderEditField.Visible = 'off';
            app.ResultFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ResultFolderEditFieldLabel.Enable = 'off';
            app.ResultFolderEditFieldLabel.Visible = 'off';
            app.ResultFolderEditFieldLabel.Text = 'ResultFolder';
            app.ResultFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ResultFolderEditField.Editable = 'off';
            app.ResultFolderEditField.Enable = 'off';
            app.ResultFolderEditField.Visible = 'off';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Enable = 'off';
            app.ExperimentEditFieldLabel.Visible = 'off';
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.ExperimentEditField.Enable = 'off';
            app.ExperimentEditField.Visible = 'off';
            app.bootEditFieldLabel = uilabel(app.UIFigure);
            app.bootEditFieldLabel.Enable = 'off';
            app.bootEditFieldLabel.Visible = 'off';
            app.bootEditFieldLabel.Text = 'boot';
            app.bootEditField = uieditfield(app.UIFigure, 'numeric');
            app.bootEditField.Editable = 'off';
            app.bootEditField.Enable = 'off';
            app.bootEditField.Visible = 'off';
            app.bin_sizeEditFieldLabel = uilabel(app.UIFigure);
            app.bin_sizeEditFieldLabel.Enable = 'off';
            app.bin_sizeEditFieldLabel.Visible = 'off';
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.bin_sizeEditField.Editable = 'off';
            app.bin_sizeEditField.Enable = 'off';
            app.bin_sizeEditField.Visible = 'off';
            app.MFREditFieldLabel = uilabel(app.UIFigure);
            app.MFREditFieldLabel.Enable = 'off';
            app.MFREditFieldLabel.Visible = 'off';
            app.MFREditFieldLabel.Text = 'MFR';
            app.MFREditField = uieditfield(app.UIFigure, 'numeric');
            app.MFREditField.Editable = 'off';
            app.MFREditField.Enable = 'off';
            app.MFREditField.Visible = 'off';
            app.NumofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofIterationsEditFieldLabel.Enable = 'off';
            app.NumofIterationsEditFieldLabel.Visible = 'off';
            app.NumofIterationsEditFieldLabel.Text = 'NumofIterations';
            app.NumofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofIterationsEditField.Editable = 'off';
            app.NumofIterationsEditField.Enable = 'off';
            app.NumofIterationsEditField.Visible = 'off';
            app.binEditFieldLabel = uilabel(app.UIFigure);
            app.binEditFieldLabel.Enable = 'off';
            app.binEditFieldLabel.Visible = 'off';
            app.binEditFieldLabel.Text = 'bin';
            app.binEditField = uieditfield(app.UIFigure, 'numeric');
            app.binEditField.Editable = 'off';
            app.binEditField.Enable = 'off';
            app.binEditField.Visible = 'off';
            app.pcEditFieldLabel = uilabel(app.UIFigure);
            app.pcEditFieldLabel.Enable = 'off';
            app.pcEditFieldLabel.Visible = 'off';
            app.pcEditFieldLabel.Text = 'pc';
            app.pcEditField = uieditfield(app.UIFigure, 'numeric');
            app.pcEditField.Editable = 'off';
            app.pcEditField.Enable = 'off';
            app.pcEditField.Visible = 'off';
            app.lvrEditFieldLabel = uilabel(app.UIFigure);
            app.lvrEditFieldLabel.Enable = 'off';
            app.lvrEditFieldLabel.Visible = 'off';
            app.lvrEditFieldLabel.Text = 'lvr';
            app.lvrEditField = uieditfield(app.UIFigure, 'numeric');
            app.lvrEditField.Editable = 'off';
            app.lvrEditField.Enable = 'off';
            app.lvrEditField.Visible = 'off';
            app.tsEditFieldLabel = uilabel(app.UIFigure);
            app.tsEditFieldLabel.Enable = 'off';
            app.tsEditFieldLabel.Visible = 'off';
            app.tsEditFieldLabel.Text = 'ts';
            app.tsEditField = uieditfield(app.UIFigure, 'numeric');
            app.tsEditField.Editable = 'off';
            app.tsEditField.Enable = 'off';
            app.tsEditField.Visible = 'off';
            app.RasterEditFieldLabel = uilabel(app.UIFigure);
            app.RasterEditFieldLabel.Enable = 'off';
            app.RasterEditFieldLabel.Visible = 'off';
            app.RasterEditFieldLabel.Text = 'Raster';
            app.RasterEditField = uieditfield(app.UIFigure, 'numeric');
            app.RasterEditField.Editable = 'off';
            app.RasterEditField.Enable = 'off';
            app.RasterEditField.Visible = 'off';
            app.RawEditFieldLabel = uilabel(app.UIFigure);
            app.RawEditFieldLabel.Enable = 'off';
            app.RawEditFieldLabel.Visible = 'off';
            app.RawEditFieldLabel.Text = 'Raw';
            app.RawEditField = uieditfield(app.UIFigure, 'numeric');
            app.RawEditField.Editable = 'off';
            app.RawEditField.Enable = 'off';
            app.RawEditField.Visible = 'off';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.mfrEditFieldLabel = uilabel(app.UIFigure);
            app.mfrEditFieldLabel.Enable = 'off';
            app.mfrEditFieldLabel.Visible = 'off';
            app.mfrEditFieldLabel.Text = 'mfr';
            app.mfrEditField = uieditfield(app.UIFigure, 'numeric');
            app.mfrEditField.Editable = 'off';
            app.mfrEditField.Enable = 'off';
            app.mfrEditField.Visible = 'off';
            app.LVRLabel = uilabel(app.UIFigure);
            app.LVRLabel.Enable = 'off';
            app.LVRLabel.Visible = 'off';
            app.LVRLabel.Text = 'LVR';
            app.LVREditField = uieditfield(app.UIFigure, 'numeric');
            app.LVREditField.Editable = 'off';
            app.LVREditField.Enable = 'off';
            app.LVREditField.Visible = 'off';
            app.BOOTLabel = uilabel(app.UIFigure);
            app.BOOTLabel.Enable = 'off';
            app.BOOTLabel.Visible = 'off';
            app.BOOTLabel.Text = 'BOOT';
            app.BOOTEditField = uieditfield(app.UIFigure, 'numeric');
            app.BOOTEditField.Editable = 'off';
            app.BOOTEditField.Enable = 'off';
            app.BOOTEditField.Visible = 'off';
            app.PCLabel = uilabel(app.UIFigure);
            app.PCLabel.Enable = 'off';
            app.PCLabel.Visible = 'off';
            app.PCLabel.Text = 'PC';
            app.PCEditField = uieditfield(app.UIFigure, 'numeric');
            app.PCEditField.Editable = 'off';
            app.PCEditField.Enable = 'off';
            app.PCEditField.Visible = 'off';
            app.YESEditFieldLabel = uilabel(app.UIFigure);
            app.YESEditFieldLabel.Enable = 'off';
            app.YESEditFieldLabel.Visible = 'off';
            app.YESEditFieldLabel.Text = 'YESEditField';
            app.YESEditField = uieditfield(app.UIFigure, 'numeric');
            app.YESEditField.ValueChangedFcn = createCallbackFcn(app, @YESEditFieldValueChanged, true);
            app.YESEditField.Editable = 'off';
            app.YESEditField.Enable = 'off';
            app.YESEditField.Visible = 'off';
            app.RawEditField_2Label = uilabel(app.UIFigure);
            app.RawEditField_2Label.Enable = 'off';
            app.RawEditField_2Label.Visible = 'off';
            app.RawEditField_2Label.Text = 'Raw';
            app.SpkEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpkEditField.Editable = 'off';
            app.SpkEditField.Enable = 'off';
            app.SpkEditField.Visible = 'off';
            app.YESEditField_2Label = uilabel(app.UIFigure);
            app.YESEditField_2Label.Enable = 'off';
            app.YESEditField_2Label.Visible = 'off';
            app.YESEditField_2Label.Text = 'YESEditField';
            app.ChannelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelsEditField.Editable = 'off';
            app.ChannelsEditField.Enable = 'off';
            app.ChannelsEditField.Visible = 'off';
            app.Alert_Spk = uilabel(app.UIFigure);
            app.Alert_Spk.Visible = 'off';
            app.Alert_Spk.Text = 'Alert_mfr';
            app.mfrEditField_2Label = uilabel(app.UIFigure);
            app.mfrEditField_2Label.Enable = 'off';
            app.mfrEditField_2Label.Visible = 'off';
            app.mfrEditField_2Label.Text = 'mfr';
            app.spkEditField = uieditfield(app.UIFigure, 'numeric');
            app.spkEditField.Editable = 'off';
            app.spkEditField.Enable = 'off';
            app.spkEditField.Visible = 'off';
            app.PTSDEditFieldLabel = uilabel(app.UIFigure);
            app.PTSDEditFieldLabel.Enable = 'off';
            app.PTSDEditFieldLabel.Visible = 'off';
            app.PTSDEditFieldLabel.Text = 'YESEditField';
            app.PTSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PTSDEditField.Editable = 'off';
            app.PTSDEditField.Enable = 'off';
            app.PTSDEditField.Visible = 'off';
            app.SWTTEOEditFieldLabel = uilabel(app.UIFigure);
            app.SWTTEOEditFieldLabel.Enable = 'off';
            app.SWTTEOEditFieldLabel.Visible = 'off';
            app.SWTTEOEditFieldLabel.Text = 'YESEditField';
            app.SWTTEOEditField = uieditfield(app.UIFigure, 'numeric');
            app.SWTTEOEditField.Editable = 'off';
            app.SWTTEOEditField.Enable = 'off';
            app.SWTTEOEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_Plot_alert(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end