classdef Analysis_allert < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ThedestinationalreadycontainsresultsforanalysisLabel  matlab.ui.control.Label
        ContinueButton                 matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        Allert_mfrLabel                matlab.ui.control.Label
        DoyouwanttoReplaceitLabel      matlab.ui.control.Label
        Duration_WindowEditFieldLabel_2  matlab.ui.control.Label
        Duration_WindowEditField       matlab.ui.control.EditField
        StartfolderEditFieldLabel      matlab.ui.control.Label
        StartfolderEditField           matlab.ui.control.EditField
        ResultFolderEditFieldLabel     matlab.ui.control.Label
        ResultFolderEditField          matlab.ui.control.EditField
        ExperimentEditFieldLabel       matlab.ui.control.Label
        ExperimentEditField            matlab.ui.control.EditField
        bootEditFieldLabel             matlab.ui.control.Label
        bootEditField                  matlab.ui.control.NumericEditField
        bin_sizeEditFieldLabel         matlab.ui.control.Label
        bin_sizeEditField              matlab.ui.control.NumericEditField
        mfrEditFieldLabel              matlab.ui.control.Label
        mfrEditField                   matlab.ui.control.NumericEditField
        NumofIterationsEditFieldLabel  matlab.ui.control.Label
        NumofIterationsEditField       matlab.ui.control.NumericEditField
        binEditFieldLabel              matlab.ui.control.Label
        binEditField                   matlab.ui.control.NumericEditField
        pcEditFieldLabel               matlab.ui.control.Label
        pcEditField                    matlab.ui.control.NumericEditField
        lvrEditFieldLabel              matlab.ui.control.Label
        lvrEditField                   matlab.ui.control.NumericEditField
        Allert_lvrLabel                matlab.ui.control.Label
        Allert_bootLabel               matlab.ui.control.Label
        Allert_pcLabel                 matlab.ui.control.Label
        Allert_tsLabel                 matlab.ui.control.Label
        tsEditFieldLabel               matlab.ui.control.Label
        tsEditField                    matlab.ui.control.NumericEditField
        RasterEditFieldLabel           matlab.ui.control.Label
        RasterEditField                matlab.ui.control.NumericEditField
        YESEditFieldLabel              matlab.ui.control.Label
        YESEditField                   matlab.ui.control.NumericEditField
        Allert_spkLabel                matlab.ui.control.Label
        tsEditField_2Label             matlab.ui.control.Label
        spkEditField                   matlab.ui.control.NumericEditField
        PTSDEditFieldLabel             matlab.ui.control.Label
        PTSDEditField                  matlab.ui.control.NumericEditField
        SWTTEOEditFieldLabel           matlab.ui.control.Label
        SWTTEOEditField                matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Analysis_panel, Allert_spk, Allert_mfr, Allert_lvr, Allert_boot, Allert_pc, Allert_ts, Startfolder, ResultFolder, Experiment, bin_size, bin, NumofIterations, Duration_Window, spk, mfr, lvr, boot, pc, ts, raster, YES, PTSD, SWTTEO)
            % Store main app object
            app.CallingApp = Analysis_panel;
            app.ExperimentEditField.Value = Experiment;
            app.StartfolderEditField.Value = Startfolder;
            app.ResultFolderEditField.Value = ResultFolder;
            app.bin_sizeEditField.Value = bin_size;
            app.binEditField.Value = bin;
            app.NumofIterationsEditField.Value = NumofIterations;
            app.Duration_WindowEditField.Value = Duration_Window;
            app.mfrEditField.Value = mfr;
            app.lvrEditField.Value = lvr;
            app.bootEditField.Value = boot;
            app.pcEditField.Value = pc;
            app.tsEditField.Value = ts;
            app.RasterEditField.Value = raster;
            app.spkEditField.Value = spk;
            app.PTSDEditField.Value = PTSD;
            app.SWTTEOEditField.Value = SWTTEO;
            app.Allert_spkLabel.Text = Allert_spk;
            app.Allert_mfrLabel.Text = Allert_mfr;
            app.Allert_lvrLabel.Text = Allert_lvr;
            app.Allert_bootLabel.Text = Allert_boot;
            app.Allert_pcLabel.Text = Allert_pc;
            app.Allert_tsLabel.Text = Allert_ts;
            app.YESEditField.Value = YES;
            
            if app.Allert_spkLabel.Text == 'K'
                app.Allert_spkLabel.Text = 'Number of Spikes';
                app.Allert_spkLabel.Visible = true;
            end
            if app.Allert_mfrLabel.Text == 'M'
                app.Allert_mfrLabel.Text = 'Mfr';
                app.Allert_mfrLabel.Visible = true;
            end
            if app.Allert_lvrLabel.Text == 'L'
                app.Allert_lvrLabel.Text = 'Lvr';
                app.Allert_lvrLabel.Visible = true;
            end
            if app.Allert_bootLabel.Text == 'B'
                app.Allert_bootLabel.Text = 'BootstrapFR (boot)';
                app.Allert_bootLabel.Visible = true;
            end
            if app.Allert_pcLabel.Text == 'P'
                app.Allert_pcLabel.Text = 'Population Coupling (PC)';
                app.Allert_pcLabel.Visible = true;
            end
            if app.Allert_tsLabel.Text == 'T'
                app.Allert_tsLabel.Text = 'TS';
                app.Allert_tsLabel.Visible = true;
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizeEditField.Value,app.binEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowEditField.Value,app.mfrEditField.Value,app.lvrEditField.Value,app.bootEditField.Value,app.pcEditField.Value,app.tsEditField.Value,app.RasterEditField.Value,app.spkEditField.Value,app.YESEditField.Value,app.PTSDEditField.Value,app.SWTTEOEditField.Value);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ThedestinationalreadycontainsresultsforanalysisLabel = uilabel(app.UIFigure);
            app.ThedestinationalreadycontainsresultsforanalysisLabel.Text = 'The destination already contains results for analysis: ';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Allert_mfrLabel = uilabel(app.UIFigure);
            app.Allert_mfrLabel.Visible = 'off';
            app.Allert_mfrLabel.Text = 'Allert_mfr';
            app.DoyouwanttoReplaceitLabel = uilabel(app.UIFigure);
            app.DoyouwanttoReplaceitLabel.Text = 'Do you want to Replace it?';
            app.Duration_WindowEditFieldLabel_2 = uilabel(app.UIFigure);
            app.Duration_WindowEditFieldLabel_2.Enable = 'off';
            app.Duration_WindowEditFieldLabel_2.Visible = 'off';
            app.Duration_WindowEditFieldLabel_2.Text = 'Duration_Window';
            app.Duration_WindowEditField = uieditfield(app.UIFigure, 'text');
            app.Duration_WindowEditField.Editable = 'off';
            app.Duration_WindowEditField.Enable = 'off';
            app.Duration_WindowEditField.Visible = 'off';
            app.StartfolderEditFieldLabel = uilabel(app.UIFigure);
            app.StartfolderEditFieldLabel.Enable = 'off';
            app.StartfolderEditFieldLabel.Visible = 'off';
            app.StartfolderEditFieldLabel.Text = 'Startfolder';
            app.StartfolderEditField = uieditfield(app.UIFigure, 'text');
            app.StartfolderEditField.Editable = 'off';
            app.StartfolderEditField.Enable = 'off';
            app.StartfolderEditField.Visible = 'off';
            app.ResultFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ResultFolderEditFieldLabel.Enable = 'off';
            app.ResultFolderEditFieldLabel.Visible = 'off';
            app.ResultFolderEditFieldLabel.Text = 'ResultFolder';
            app.ResultFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ResultFolderEditField.Editable = 'off';
            app.ResultFolderEditField.Enable = 'off';
            app.ResultFolderEditField.Visible = 'off';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Enable = 'off';
            app.ExperimentEditFieldLabel.Visible = 'off';
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.ExperimentEditField.Enable = 'off';
            app.ExperimentEditField.Visible = 'off';
            app.bootEditFieldLabel = uilabel(app.UIFigure);
            app.bootEditFieldLabel.Enable = 'off';
            app.bootEditFieldLabel.Visible = 'off';
            app.bootEditFieldLabel.Text = 'boot';
            app.bootEditField = uieditfield(app.UIFigure, 'numeric');
            app.bootEditField.Editable = 'off';
            app.bootEditField.Enable = 'off';
            app.bootEditField.Visible = 'off';
            app.bin_sizeEditFieldLabel = uilabel(app.UIFigure);
            app.bin_sizeEditFieldLabel.Enable = 'off';
            app.bin_sizeEditFieldLabel.Visible = 'off';
            app.bin_sizeEditFieldLabel.Text = 'bin_size';
            app.bin_sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.bin_sizeEditField.Editable = 'off';
            app.bin_sizeEditField.Enable = 'off';
            app.bin_sizeEditField.Visible = 'off';
            app.mfrEditFieldLabel = uilabel(app.UIFigure);
            app.mfrEditFieldLabel.Enable = 'off';
            app.mfrEditFieldLabel.Visible = 'off';
            app.mfrEditFieldLabel.Text = 'mfr';
            app.mfrEditField = uieditfield(app.UIFigure, 'numeric');
            app.mfrEditField.Editable = 'off';
            app.mfrEditField.Enable = 'off';
            app.mfrEditField.Visible = 'off';
            app.NumofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofIterationsEditFieldLabel.Enable = 'off';
            app.NumofIterationsEditFieldLabel.Visible = 'off';
            app.NumofIterationsEditFieldLabel.Text = 'NumofIterations';
            app.NumofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofIterationsEditField.Editable = 'off';
            app.NumofIterationsEditField.Enable = 'off';
            app.NumofIterationsEditField.Visible = 'off';
            app.binEditFieldLabel = uilabel(app.UIFigure);
            app.binEditFieldLabel.Enable = 'off';
            app.binEditFieldLabel.Visible = 'off';
            app.binEditFieldLabel.Text = 'bin';
            app.binEditField = uieditfield(app.UIFigure, 'numeric');
            app.binEditField.Editable = 'off';
            app.binEditField.Enable = 'off';
            app.binEditField.Visible = 'off';
            app.pcEditFieldLabel = uilabel(app.UIFigure);
            app.pcEditFieldLabel.Enable = 'off';
            app.pcEditFieldLabel.Visible = 'off';
            app.pcEditFieldLabel.Text = 'pc';
            app.pcEditField = uieditfield(app.UIFigure, 'numeric');
            app.pcEditField.Editable = 'off';
            app.pcEditField.Enable = 'off';
            app.pcEditField.Visible = 'off';
            app.lvrEditFieldLabel = uilabel(app.UIFigure);
            app.lvrEditFieldLabel.Enable = 'off';
            app.lvrEditFieldLabel.Visible = 'off';
            app.lvrEditFieldLabel.Text = 'lvr';
            app.lvrEditField = uieditfield(app.UIFigure, 'numeric');
            app.lvrEditField.Editable = 'off';
            app.lvrEditField.Enable = 'off';
            app.lvrEditField.Visible = 'off';
            app.Allert_lvrLabel = uilabel(app.UIFigure);
            app.Allert_lvrLabel.Visible = 'off';
            app.Allert_lvrLabel.Text = 'Allert_lvr';
            app.Allert_bootLabel = uilabel(app.UIFigure);
            app.Allert_bootLabel.Visible = 'off';
            app.Allert_bootLabel.Text = 'Allert_boot';
            app.Allert_pcLabel = uilabel(app.UIFigure);
            app.Allert_pcLabel.Visible = 'off';
            app.Allert_pcLabel.Text = 'Allert_pc';
            app.Allert_tsLabel = uilabel(app.UIFigure);
            app.Allert_tsLabel.Visible = 'off';
            app.Allert_tsLabel.Text = 'Allert_ts';
            app.tsEditFieldLabel = uilabel(app.UIFigure);
            app.tsEditFieldLabel.Enable = 'off';
            app.tsEditFieldLabel.Visible = 'off';
            app.tsEditFieldLabel.Text = 'ts';
            app.tsEditField = uieditfield(app.UIFigure, 'numeric');
            app.tsEditField.Editable = 'off';
            app.tsEditField.Enable = 'off';
            app.tsEditField.Visible = 'off';
            app.RasterEditFieldLabel = uilabel(app.UIFigure);
            app.RasterEditFieldLabel.Enable = 'off';
            app.RasterEditFieldLabel.Visible = 'off';
            app.RasterEditFieldLabel.Text = 'Raster';
            app.RasterEditField = uieditfield(app.UIFigure, 'numeric');
            app.RasterEditField.Editable = 'off';
            app.RasterEditField.Enable = 'off';
            app.RasterEditField.Visible = 'off';
            app.YESEditFieldLabel = uilabel(app.UIFigure);
            app.YESEditFieldLabel.Enable = 'off';
            app.YESEditFieldLabel.Visible = 'off';
            app.YESEditFieldLabel.Text = 'YESEditField';
            app.YESEditField = uieditfield(app.UIFigure, 'numeric');
            app.YESEditField.Editable = 'off';
            app.YESEditField.Enable = 'off';
            app.YESEditField.Visible = 'off';
            app.Allert_spkLabel = uilabel(app.UIFigure);
            app.Allert_spkLabel.Visible = 'off';
            app.Allert_spkLabel.Text = 'Allert_spk';
            app.tsEditField_2Label = uilabel(app.UIFigure);
            app.tsEditField_2Label.Enable = 'off';
            app.tsEditField_2Label.Visible = 'off';
            app.tsEditField_2Label.Text = 'ts';
            app.spkEditField = uieditfield(app.UIFigure, 'numeric');
            app.spkEditField.Editable = 'off';
            app.spkEditField.Enable = 'off';
            app.spkEditField.Visible = 'off';
            app.PTSDEditFieldLabel = uilabel(app.UIFigure);
            app.PTSDEditFieldLabel.Enable = 'off';
            app.PTSDEditFieldLabel.Visible = 'off';
            app.PTSDEditFieldLabel.Text = 'YESEditField';
            app.PTSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PTSDEditField.Editable = 'off';
            app.PTSDEditField.Enable = 'off';
            app.PTSDEditField.Visible = 'off';
            app.SWTTEOEditFieldLabel = uilabel(app.UIFigure);
            app.SWTTEOEditFieldLabel.Enable = 'off';
            app.SWTTEOEditFieldLabel.Visible = 'off';
            app.SWTTEOEditFieldLabel.Text = 'YESEditField';
            app.SWTTEOEditField = uieditfield(app.UIFigure, 'numeric');
            app.SWTTEOEditField.Editable = 'off';
            app.SWTTEOEditField.Enable = 'off';
            app.SWTTEOEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_allert(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end