classdef Analysis_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIAnalysisUIFigure           matlab.ui.Figure
        AnalysisListBoxLabel         matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        QuitButton                   matlab.ui.control.Button
        ParametersLabel              matlab.ui.control.Label
        bin_sizesecEditFieldLabel    matlab.ui.control.Label
        bin_sizesecEditField         matlab.ui.control.NumericEditField
        binsecEditFieldLabel         matlab.ui.control.Label
        binsecEditField              matlab.ui.control.NumericEditField
        NumofIterationsLabel         matlab.ui.control.Label
        NumofIterationsEditField     matlab.ui.control.NumericEditField
        MfrCheckBox                  matlab.ui.control.CheckBox
        LvRCheckBox                  matlab.ui.control.CheckBox
        Boot_statsCheckBox           matlab.ui.control.CheckBox
        PCCheckBox                   matlab.ui.control.CheckBox
        SelectAllCheckBox            matlab.ui.control.CheckBox
        ExperimentEditFieldLabel     matlab.ui.control.Label
        ExperimentEditField          matlab.ui.control.EditField
        StartfolderEditFieldLabel    matlab.ui.control.Label
        StartfolderEditField         matlab.ui.control.EditField
        ResultFolderEditFieldLabel   matlab.ui.control.Label
        ResultFolderEditField        matlab.ui.control.EditField
        Duration_WindowminEditFieldLabel  matlab.ui.control.Label
        Duration_WindowminEditField  matlab.ui.control.EditField
        Result_folder_durationLabel  matlab.ui.control.Label
        Alert_mfrLabel               matlab.ui.control.Label
        ExpDetailsLabel              matlab.ui.control.Label
        ShowButton                   matlab.ui.control.Button
        Alert_lvrLabel               matlab.ui.control.Label
        Alert_bootLabel              matlab.ui.control.Label
        Alert_pcLabel                matlab.ui.control.Label
        TSCheckBox                   matlab.ui.control.CheckBox
        RasterEditFieldLabel         matlab.ui.control.Label
        RasterEditField              matlab.ui.control.NumericEditField
        Alert_tsLabel                matlab.ui.control.Label
        SortedLabel                  matlab.ui.control.Label
        YesCheckBox                  matlab.ui.control.CheckBox
        NoCheckBox                   matlab.ui.control.CheckBox
        Alert_YES_SortedLabel        matlab.ui.control.Label
        Alert_NO_SortedLabel         matlab.ui.control.Label
        SpkCheckBox                  matlab.ui.control.CheckBox
        Alert_SpkLabel               matlab.ui.control.Label
        WhichSpikeDetectionLabel     matlab.ui.control.Label
        PTSDCheckBox                 matlab.ui.control.CheckBox
        SWTTEOCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp % Single_Exp_Analysis (Main App) object
        DialogApp_analysis_alert % Dialog box (Analysis allert)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Single_Exp_Analysis, exp, res_folder, start_folder)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = Single_Exp_Analysis;
            
            % Update UI with input values
            app.ExperimentEditField.Value = exp;
            app.StartfolderEditField.Value = start_folder;
            app.ResultFolderEditField.Value = res_folder;
        end

        % Callback function
        function SelectAllCheckBoxValueChanged(app, event)
            
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            mfr=0;
            lvr=0;
            boot=0;
            pc=0;
            spk=0;
            if app.YesCheckBox.Value == true
                YES=1;
            else
                YES=0;
            end
            if app.SpkCheckBox.Value == true
                SPK=1;
            else
                SPK=0;
            end
            if app.MfrCheckBox.Value == true
                MFR=1;
            else
                MFR=0;
            end
            if app.LvRCheckBox.Value == true
                LVR=1;
            else
                LVR=0;
            end
            if app.Boot_statsCheckBox.Value == true
                BOOT=1;
            else
                BOOT=0;
            end
            if app.TSCheckBox.Value == true
                TS=1;
            else
                TS=0;
            end
            if app.PCCheckBox.Value == true
                PC=1;
            else
                PC=0;
            end
            if app.PTSDCheckBox.Value == true
                PTSD = 1;
                SWTTEO = 0;
            else
                PTSD = 0;
                SWTTEO = 1;
            end
            
            RASTER=0;
            RAW=0;
            
            if SPK==0 && TS==0 && PC==0 && BOOT==0 && LVR==0 && MFR==0 && app.SelectAllCheckBox.Value == false
                msgbox('Select Analysis','Allert','warn')
            else
                
                % Editable Fields False
                app.bin_sizesecEditField.Editable = false;
                app.binsecEditField.Editable = false;
                if app.Duration_WindowminEditField.Value == '-'
                    app.Duration_WindowminEditField.Value ='1';
                end
                app.Duration_WindowminEditField.Editable = false;
                app.NumofIterationsEditField.Editable = false;
                % Enable Check False
                app.SelectAllCheckBox.Enable = false;
                app.SpkCheckBox.Enable = false;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
                
                app.StartButton.Enable = 'off';
                
                % Control if already analyzed
                if app.Duration_WindowminEditField.Value == '1'
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentEditField.Value '_DurationFixed_Original_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                else
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentEditField.Value '_DurationFixed_' app.Duration_WindowminEditField.Value '_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                end
                if exist(res_duration,'dir')==0
                    mkdir(res_duration)
                end
                app.ResultFolderEditField.Value = res_duration;
                cd(app.ResultFolderEditField.Value)
                if exist(res_duration,'dir')==7
                    an_spk=(fullfile(res_duration,'/Analysis/','SPK.mat'));
                    K=0;
                    if exist(an_spk,'file') == 2 && SPK==1
                        app.Alert_SpkLabel.Text = 'K';
                        K=1;
                    end
                    an_mfr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_MFR_' app.Duration_WindowminEditField.Value '.mat']));
                    M=0;
                    if exist(an_mfr,'file') == 2 && MFR==1
                        app.Alert_mfrLabel.Text = 'M';
                        M=1;
                    end
                    an_lvr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_LVR_' app.Duration_WindowminEditField.Value '.mat']));
                    L=0;
                    if exist(an_lvr,'file') == 2 && LVR==1
                        app.Alert_lvrLabel.Text = 'L';
                        L=1;
                    end
                    an_boot=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_FR_' app.Duration_WindowminEditField.Value '.mat']));
                    B=0;
                    if exist(an_boot,'file') == 2 && BOOT==1
                        app.Alert_bootLabel.Text = 'B';
                        B=1;
                    end
                    an_pc=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_PC_' app.Duration_WindowminEditField.Value '.mat']));
                    P=0;
                    if exist(an_pc,'file') == 2 && PC==1
                        app.Alert_pcLabel.Text = 'P';
                        P=1;
                    end
                    an_ts=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_TS_' app.Duration_WindowminEditField.Value '.mat']));
                    T=0;
                    if exist(an_ts,'file') == 2 && TS==1
                        app.Alert_tsLabel.Text = 'T';
                        T=1;
                    end
                    if K == 1 || M == 1 || L == 1 || B == 1 || P == 1 || T == 1
                        % Call dialog box with input values
                        app.DialogApp_analysis_alert = Analysis_allert(app,app.Alert_SpkLabel,app.Alert_mfrLabel.Text,app.Alert_lvrLabel.Text,app.Alert_bootLabel.Text,app.Alert_pcLabel.Text,app.Alert_tsLabel.Text,app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,SPK,MFR,LVR,BOOT,PC,TS,RASTER,YES,PTSD,SWTTEO);
                    else
                        Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO);
                    end
                else
                    Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO);
                end
                
                delete(app)
            end
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged2(app, event)
            value = app.SelectAllCheckBox.Value;
            if value == true
                app.SpkCheckBox.Value = true;
                app.MfrCheckBox.Value = true;
                app.LvRCheckBox.Value = true;
                app.Boot_statsCheckBox.Value = true;
                app.PCCheckBox.Value = true;
                app.TSCheckBox.Value = true;
                app.SpkCheckBox.Enable = false;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
            else
                app.SpkCheckBox.Enable = true;
                app.MfrCheckBox.Enable = true;
                app.LvRCheckBox.Enable = true;
                app.Boot_statsCheckBox.Enable = true;
                app.PCCheckBox.Enable = true;
                app.TSCheckBox.Enable = true;
                app.SpkCheckBox.Value = false;
                app.MfrCheckBox.Value = false;
                app.LvRCheckBox.Value = false;
                app.Boot_statsCheckBox.Value = false;
                app.PCCheckBox.Value = false;
                app.TSCheckBox.Value = false;
            end
        end

        % Close request function: UIAnalysisUIFigure
        function UIAnalysisUIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            cd(app.ResultFolderEditField.Value)
            msgbox(ls,'Details',"modal")
        end

        % Value changed function: YesCheckBox
        function YesCheckBoxValueChanged(app, event)
            value = app.YesCheckBox.Value;
            if value == true
                app.NoCheckBox.Value = false;
                app.NoCheckBox.Enable = false;
            else
                app.NoCheckBox.Value = true;
                app.NoCheckBox.Enable = true;
                app.YesCheckBox.Enable = false;
            end
        end

        % Value changed function: PTSDCheckBox
        function PTSDCheckBoxValueChanged(app, event)
            value = app.PTSDCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = true;
                app.SWTTEOCheckBox.Value = true;
                app.PTSDCheckBox.Enable = false;
                PTSD = 0;
                SWTTEO = 1;
            end
            
        end

        % Value changed function: SWTTEOCheckBox
        function SWTTEOCheckBoxValueChanged(app, event)
            value = app.SWTTEOCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = false;
                app.PTSDCheckBox.Enable = true;
                app.PTSDCheckBox.Value = true;
                PTSD = 1;
                SWTTEO = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIAnalysisUIFigure = uifigure('Visible', 'off');
            app.UIAnalysisUIFigure.Name = 'UI Analysis';
            app.UIAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIAnalysisUIFigureCloseRequest, true);
            app.AnalysisListBoxLabel = uilabel(app.UIAnalysisUIFigure);
            app.AnalysisListBoxLabel.Text = 'Analysis:';
            app.StartButton = uibutton(app.UIAnalysisUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIAnalysisUIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ParametersLabel = uilabel(app.UIAnalysisUIFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.bin_sizesecEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.bin_sizesecEditFieldLabel.Text = 'bin_size [sec]';
            app.bin_sizesecEditField = uieditfield(app.UIAnalysisUIFigure, 'numeric');
            app.bin_sizesecEditField.Value = 60;
            app.binsecEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.binsecEditFieldLabel.Text = 'bin [sec]';
            app.binsecEditField = uieditfield(app.UIAnalysisUIFigure, 'numeric');
            app.binsecEditField.Value = 1;
            app.NumofIterationsLabel = uilabel(app.UIAnalysisUIFigure);
            app.NumofIterationsLabel.Text = 'NumofIterations ';
            app.NumofIterationsEditField = uieditfield(app.UIAnalysisUIFigure, 'numeric');
            app.NumofIterationsEditField.Value = 1000;
            app.MfrCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.MfrCheckBox.Enable = 'off';
            app.MfrCheckBox.Text = 'Mfr';
            app.MfrCheckBox.Value = true;
            app.LvRCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.LvRCheckBox.Enable = 'off';
            app.LvRCheckBox.Text = 'LvR';
            app.LvRCheckBox.Value = true;
            app.Boot_statsCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.Boot_statsCheckBox.Enable = 'off';
            app.Boot_statsCheckBox.Text = 'Boot_stats';
            app.Boot_statsCheckBox.Value = true;
            app.PCCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.PCCheckBox.Enable = 'off';
            app.PCCheckBox.Text = 'PC';
            app.PCCheckBox.Value = true;
            app.SelectAllCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged2, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SelectAllCheckBox.Value = true;
            app.ExperimentEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.UIAnalysisUIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.ExperimentEditField.Enable = 'off';
            app.StartfolderEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.StartfolderEditFieldLabel.Enable = 'off';
            app.StartfolderEditFieldLabel.Visible = 'off';
            app.StartfolderEditFieldLabel.Text = 'Start folder';
            app.StartfolderEditField = uieditfield(app.UIAnalysisUIFigure, 'text');
            app.StartfolderEditField.Editable = 'off';
            app.StartfolderEditField.Enable = 'off';
            app.StartfolderEditField.Visible = 'off';
            app.ResultFolderEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.ResultFolderEditFieldLabel.Enable = 'off';
            app.ResultFolderEditFieldLabel.Visible = 'off';
            app.ResultFolderEditFieldLabel.Text = 'Result Folder';
            app.ResultFolderEditField = uieditfield(app.UIAnalysisUIFigure, 'text');
            app.ResultFolderEditField.Editable = 'off';
            app.ResultFolderEditField.Enable = 'off';
            app.ResultFolderEditField.Visible = 'off';
            app.Duration_WindowminEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.Duration_WindowminEditFieldLabel.Text = 'Duration_Window [min]';
            app.Duration_WindowminEditField = uieditfield(app.UIAnalysisUIFigure, 'text');
            app.Duration_WindowminEditField.Value = '-';
            app.Result_folder_durationLabel = uilabel(app.UIAnalysisUIFigure);
            app.Result_folder_durationLabel.Enable = 'off';
            app.Result_folder_durationLabel.Visible = 'off';
            app.Result_folder_durationLabel.Text = 'Result_folder_duration';
            app.Alert_mfrLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_mfrLabel.Enable = 'off';
            app.Alert_mfrLabel.Visible = 'off';
            app.Alert_mfrLabel.Text = 'Alert_mfr';
            app.ExpDetailsLabel = uilabel(app.UIAnalysisUIFigure);
            app.ExpDetailsLabel.Text = 'Exp Details';
            app.ShowButton = uibutton(app.UIAnalysisUIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.Alert_lvrLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_lvrLabel.Enable = 'off';
            app.Alert_lvrLabel.Visible = 'off';
            app.Alert_lvrLabel.Text = 'Alert_lvr';
            app.Alert_bootLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_bootLabel.Enable = 'off';
            app.Alert_bootLabel.Visible = 'off';
            app.Alert_bootLabel.Text = 'Alert_boot';
            app.Alert_pcLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_pcLabel.Enable = 'off';
            app.Alert_pcLabel.Visible = 'off';
            app.Alert_pcLabel.Text = 'Alert_pc';
            app.TSCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.TSCheckBox.Enable = 'off';
            app.TSCheckBox.Text = 'TS';
            app.TSCheckBox.Value = true;
            app.RasterEditFieldLabel = uilabel(app.UIAnalysisUIFigure);
            app.RasterEditFieldLabel.Enable = 'off';
            app.RasterEditFieldLabel.Visible = 'off';
            app.RasterEditFieldLabel.Text = 'Raster';
            app.RasterEditField = uieditfield(app.UIAnalysisUIFigure, 'numeric');
            app.RasterEditField.Editable = 'off';
            app.RasterEditField.Enable = 'off';
            app.RasterEditField.Visible = 'off';
            app.Alert_tsLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_tsLabel.Enable = 'off';
            app.Alert_tsLabel.Visible = 'off';
            app.Alert_tsLabel.Text = 'Alert_ts';
            app.SortedLabel = uilabel(app.UIAnalysisUIFigure);
            app.SortedLabel.Text = 'Sorted?';
            app.YesCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.YesCheckBox.ValueChangedFcn = createCallbackFcn(app, @YesCheckBoxValueChanged, true);
            app.YesCheckBox.Text = 'Yes';
            app.YesCheckBox.Value = true;
            app.NoCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.NoCheckBox.Enable = 'off';
            app.NoCheckBox.Text = 'No';
            app.Alert_YES_SortedLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_YES_SortedLabel.Enable = 'off';
            app.Alert_YES_SortedLabel.Visible = 'off';
            app.Alert_YES_SortedLabel.Text = 'Alert_YES_Sorted';
            app.Alert_NO_SortedLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_NO_SortedLabel.Enable = 'off';
            app.Alert_NO_SortedLabel.Visible = 'off';
            app.Alert_NO_SortedLabel.Text = 'Alert_NO_Sorted';
            app.SpkCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.SpkCheckBox.Enable = 'off';
            app.SpkCheckBox.Text = 'Spk';
            app.SpkCheckBox.Value = true;
            app.Alert_SpkLabel = uilabel(app.UIAnalysisUIFigure);
            app.Alert_SpkLabel.Enable = 'off';
            app.Alert_SpkLabel.Visible = 'off';
            app.Alert_SpkLabel.Text = 'Alert_boot';
            app.WhichSpikeDetectionLabel = uilabel(app.UIAnalysisUIFigure);
            app.WhichSpikeDetectionLabel.Text = 'Which Spike Detection?';
            app.PTSDCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.PTSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTSDCheckBoxValueChanged, true);
            app.PTSDCheckBox.Text = 'PTSD';
            app.PTSDCheckBox.Value = true;
            app.SWTTEOCheckBox = uicheckbox(app.UIAnalysisUIFigure);
            app.SWTTEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @SWTTEOCheckBoxValueChanged, true);
            app.SWTTEOCheckBox.Enable = 'off';
            app.SWTTEOCheckBox.Text = 'SWTTEO';
            % Show the figure after all components are created
            app.UIAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Analysis_panel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIAnalysisUIFigure)
        end
    end
end