classdef Data_split < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GotoProcessedDataFolderandSelecttheexperimentLabel  matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        SelectExperimentButton    matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        ExampleLabel              matlab.ui.control.Label
        Experiment_selectedLabel  matlab.ui.control.Label
        DidyouSortdataLabel       matlab.ui.control.Label
        YesCheckBox               matlab.ui.control.CheckBox
        NoCheckBox                matlab.ui.control.CheckBox
        Alert_YES_SortedLabel     matlab.ui.control.Label
        Alert_NO_SortedLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectExperimentButton
        function SelectExperimentButtonPushed(app, event)
            if app.YesCheckBox.Value == true
                YES=1;
            else
                YES=0;
            end
            % Disable Plot Options button while dialog is open
            app.SelectExperimentButton.Enable = false;
            
            [start_folder]= selectfolder('Select Experiment');
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            app.Experiment_selectedLabel.Text = start_folder;
            cd(start_folder)
            
            %             [path, exp_num, ext] = fileparts(start_folder);
            
            ndir=0;
            AllPhases = dir(fullfile(start_folder));
            
            for i = 1:numel(AllPhases)
                if AllPhases(i).isdir
                    ndir=1+ndir;
                    Phases(ndir)=AllPhases(i);
                    disp(AllPhases(i).name); % disp displays the value of the input variable
                end
            end
            
            for i=1
                sorted_split=(fullfile(start_folder, Phases(i+2).name,[Phases(i+2).name '_Sorted_splitted']));
                if exist(sorted_split,'dir')==7
                    % Disable Plot Options button while dialog is open
                    app.SelectExperimentButton.Enable = 'off';
                    
                    % Call dialog box with input values
                    app.DialogApp = Data_split_allert(app,app.Experiment_selectedLabel.Text,YES);
                else
                    app.SelectExperimentButton.Enable = false;
                    Split_Sorted_inj(app.Experiment_selectedLabel.Text,YES);
                    delete(app)
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Value changed function: YesCheckBox
        function YesCheckBoxValueChanged(app, event)
            value = app.YesCheckBox.Value;
            if value == true
                app.NoCheckBox.Value = false;
                app.NoCheckBox.Enable = false;
                app.YesCheckBox.Value = true;
                app.YesCheckBox.Enable = true;
            else
                app.NoCheckBox.Value = true;
                app.NoCheckBox.Enable = true;
                app.YesCheckBox.Enable = true;
                app.YesCheckBox.Value = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GotoProcessedDataFolderandSelecttheexperimentLabel = uilabel(app.UIFigure);
            app.GotoProcessedDataFolderandSelecttheexperimentLabel.Text = 'Go to Processed Data Folder and Select the experiment.  ';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'/Volumes/chiappalone/MAECI/MAECI -Anesthetized/Injured/Processed/Controls ET-1/R18-90 '};
            app.SelectExperimentButton = uibutton(app.UIFigure, 'push');
            app.SelectExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentButtonPushed, true);
            app.SelectExperimentButton.Text = 'Select Experiment';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ExampleLabel = uilabel(app.UIFigure);
            app.ExampleLabel.Text = 'Example:';
            app.Experiment_selectedLabel = uilabel(app.UIFigure);
            app.Experiment_selectedLabel.Enable = 'off';
            app.Experiment_selectedLabel.Visible = 'off';
            app.Experiment_selectedLabel.Text = 'Experiment_selected';
            app.DidyouSortdataLabel = uilabel(app.UIFigure);
            app.DidyouSortdataLabel.Text = 'Did you Sort data?';
            app.YesCheckBox = uicheckbox(app.UIFigure);
            app.YesCheckBox.ValueChangedFcn = createCallbackFcn(app, @YesCheckBoxValueChanged, true);
            app.YesCheckBox.Text = 'Yes';
            app.YesCheckBox.Value = true;
            app.NoCheckBox = uicheckbox(app.UIFigure);
            app.NoCheckBox.Enable = 'off';
            app.NoCheckBox.Text = 'No';
            app.Alert_YES_SortedLabel = uilabel(app.UIFigure);
            app.Alert_YES_SortedLabel.Enable = 'off';
            app.Alert_YES_SortedLabel.Visible = 'off';
            app.Alert_YES_SortedLabel.Text = 'Alert_YES_Sorted';
            app.Alert_NO_SortedLabel = uilabel(app.UIFigure);
            app.Alert_NO_SortedLabel.Enable = 'off';
            app.Alert_NO_SortedLabel.Visible = 'off';
            app.Alert_NO_SortedLabel.Text = 'Alert_NO_Sorted';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_split

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end