classdef Data_split_allert < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ThedestinationalreadycontainsafoldernamedLabel  matlab.ui.control.Label
        ContinueButton             matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        Start_folder_nameLabel     matlab.ui.control.Label
        DoyouwanttoReplaceitLabel  matlab.ui.control.Label
        YESEditFieldLabel          matlab.ui.control.Label
        YESEditField               matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Main App object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data_split, start_folder, YES)
            % Store main app object
            app.CallingApp = Data_split;
            app.YESEditField.Value = YES;
            app.Start_folder_nameLabel.Text = start_folder;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.ContinueButton.Enable = false;
            Split_Sorted_inj(app.Start_folder_nameLabel.Text,app.YESEditField.Value);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThedestinationalreadycontainsafoldernamedLabel = uilabel(app.UIFigure);
            app.ThedestinationalreadycontainsafoldernamedLabel.Text = 'The destination already contains a folder named: ';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Start_folder_nameLabel = uilabel(app.UIFigure);
            app.Start_folder_nameLabel.Text = 'Start_folder_name';
            app.DoyouwanttoReplaceitLabel = uilabel(app.UIFigure);
            app.DoyouwanttoReplaceitLabel.Text = 'Do you want to Replace it?';
            app.YESEditFieldLabel = uilabel(app.UIFigure);
            app.YESEditFieldLabel.Enable = 'off';
            app.YESEditFieldLabel.Visible = 'off';
            app.YESEditFieldLabel.Text = 'YESEditField';
            app.YESEditField = uieditfield(app.UIFigure, 'numeric');
            app.YESEditField.Editable = 'off';
            app.YESEditField.Enable = 'off';
            app.YESEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Data_split_allert(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end