classdef Group_Selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Analysis                  matlab.ui.Figure
        SelectExperimentsButton   matlab.ui.control.Button
        GroupExperimentLabel      matlab.ui.control.Label
        SelectAnalysisButton      matlab.ui.control.Button
        QuitButton                matlab.ui.control.Button
        ExperimentEditFieldLabel  matlab.ui.control.Label
        ExperimentEditField       matlab.ui.control.EditField
        ResultsLabel              matlab.ui.control.Label
        SelectFolderButton        matlab.ui.control.Button
        ResultFolderLabel         matlab.ui.control.Label
        ResultFolderTextArea      matlab.ui.control.TextArea
        StartfolderLabel          matlab.ui.control.Label
        ResultLabel               matlab.ui.control.Label
        ShowButton                matlab.ui.control.Button
        ExperimentsDetailsLabel   matlab.ui.control.Label
        ListBoxLabel              matlab.ui.control.Label
        ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        DialogApp % Dialog box app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectExperimentsButton
        function SelectExperimentsButtonPushed(app, event)
            [start_folder]= selectfolder('Select Experiment'); % SpyCode_Function
            
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            cd(start_folder)
            [file,path] = uigetfile('Select One or More Animals','MultiSelect', 'on');
       
            app.StartfolderLabel.Text = start_folder;
            app.ExperimentEditField.Value = cell2mat(file);
            app.SelectExperimentsButton.Enable = false;
            app.SelectFolderButton.Enable = true;
        end

        % Button pushed function: SelectAnalysisButton
        function SelectAnalysisButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SelectAnalysisButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.DialogApp = Analysis_panel(app, app.ExperimentEditField.Value, app.ResultLabel.Text, app.StartfolderLabel.Text);
            delete(app)
        end

        % Close request function: Analysis
        function AnalysisCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Value changed function: ExperimentEditField
        function ExperimentEditFieldValueChanged(app, event)
            value = app.ExperimentEditField.Value;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % set Result Folder
            res_path = selectfolder('Select Experiment');
            Res_fold=(fullfile([res_path '/' app.ExperimentEditField.Value '_STATS']));
            app.ResultFolderTextArea.Value = Res_fold;
            app.ResultLabel.Text = Res_fold;
            if exist(Res_fold,'dir')==0
                mkdir(Res_fold)
            end
            app.SelectFolderButton.Enable = false;
            app.ShowButton.Enable = false;
            app.SelectAnalysisButton.Enable = true;
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            [details_folder]= selectfolder('Select Experiment'); % SpyCode_Function
            
            if strcmp(num2str(details_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            
            f = waitbar(0,'Please wait...');
            pause(.5)
            load(fullfile([details_folder '_Tank.mat']))
            tankObj.nigelDash
            close(f)
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Analysis = uifigure('Visible', 'off');
            app.Analysis.Name = 'UI Figure';
            app.Analysis.CloseRequestFcn = createCallbackFcn(app, @AnalysisCloseRequest, true);
            app.SelectExperimentsButton = uibutton(app.Analysis, 'push');
            app.SelectExperimentsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentsButtonPushed, true);
            app.SelectExperimentsButton.Text = 'Select Experiments';
            app.GroupExperimentLabel = uilabel(app.Analysis);
            app.GroupExperimentLabel.Text = 'Group Experiment';
            app.SelectAnalysisButton = uibutton(app.Analysis, 'push');
            app.SelectAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAnalysisButtonPushed, true);
            app.SelectAnalysisButton.Enable = 'off';
            app.SelectAnalysisButton.Text = 'Select Analysis';
            app.QuitButton = uibutton(app.Analysis, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ExperimentEditFieldLabel = uilabel(app.Analysis);
            app.ExperimentEditFieldLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.Analysis, 'text');
            app.ExperimentEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentEditFieldValueChanged, true);
            app.ExperimentEditField.Editable = 'off';
            app.ResultsLabel = uilabel(app.Analysis);
            app.ResultsLabel.Text = 'Results ';
            app.SelectFolderButton = uibutton(app.Analysis, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Enable = 'off';
            app.SelectFolderButton.Text = 'Select Folder';
            app.ResultFolderLabel = uilabel(app.Analysis);
            app.ResultFolderLabel.Text = 'Result Folder:';
            app.ResultFolderTextArea = uitextarea(app.Analysis);
            app.ResultFolderTextArea.Editable = 'off';
            app.StartfolderLabel = uilabel(app.Analysis);
            app.StartfolderLabel.Enable = 'off';
            app.StartfolderLabel.Visible = 'off';
            app.StartfolderLabel.Text = 'Start folder';
            app.ResultLabel = uilabel(app.Analysis);
            app.ResultLabel.Enable = 'off';
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'ResultLabel';
            app.ShowButton = uibutton(app.Analysis, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ExperimentsDetailsLabel = uilabel(app.Analysis);
            app.ExperimentsDetailsLabel.Text = 'Experiments Details';
            app.ListBoxLabel = uilabel(app.Analysis);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.Analysis);
            app.ListBox.Items = {'Item 1', 'Item 5', 'Item 2', 'Item 3', 'Item 4'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            % Show the figure after all components are created
            app.Analysis.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group_Selection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Analysis)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Analysis)
        end
    end
end