classdef Group_selection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnalysisListBoxLabel           matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        QuitButton                     matlab.ui.control.Button
        SelectAllCheckBox              matlab.ui.control.CheckBox
        Alert_mfrLabel                 matlab.ui.control.Label
        ExpDetailsLabel                matlab.ui.control.Label
        ShowButton                     matlab.ui.control.Button
        Alert_tsLabel                  matlab.ui.control.Label
        ExperimentsEditFieldLabel      matlab.ui.control.Label
        ExperimentsEditField           matlab.ui.control.EditField
        StartfolderEditField           matlab.ui.control.EditField
        ResultFolderEditFieldLabel     matlab.ui.control.Label
        ResultFolderEditField          matlab.ui.control.EditField
        SelectGroupButton              matlab.ui.control.Button
        GroupExperimentsLabel          matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        ResultsFolderLabel             matlab.ui.control.Label
        MfrCheckBox                    matlab.ui.control.CheckBox
        LvRCheckBox                    matlab.ui.control.CheckBox
        Boot_statsCheckBox             matlab.ui.control.CheckBox
        PCCheckBox                     matlab.ui.control.CheckBox
        Result_folder_durationLabel    matlab.ui.control.Label
        Alert_lvrLabel                 matlab.ui.control.Label
        Alert_bootLabel                matlab.ui.control.Label
        Alert_pcLabel                  matlab.ui.control.Label
        TSCheckBox                     matlab.ui.control.CheckBox
        Alert_tsLabel_2                matlab.ui.control.Label
        SpkCheckBox                    matlab.ui.control.CheckBox
        Alert_SpkLabel                 matlab.ui.control.Label
        ParametersLabel                matlab.ui.control.Label
        bin_sizesecEditFieldLabel      matlab.ui.control.Label
        bin_sizesecEditField           matlab.ui.control.NumericEditField
        binsecEditFieldLabel           matlab.ui.control.Label
        binsecEditField                matlab.ui.control.NumericEditField
        NumofIterationsEditFieldLabel  matlab.ui.control.Label
        NumofIterationsEditField       matlab.ui.control.NumericEditField
        Duration_WindowminEditFieldLabel  matlab.ui.control.Label
        Duration_WindowminEditField    matlab.ui.control.EditField
        ResultsEditFieldLabel          matlab.ui.control.Label
        ResultsEditField               matlab.ui.control.EditField
        ChangeButton                   matlab.ui.control.Button
        StartFolderLabel               matlab.ui.control.Label
        WhichSpikeDetectionLabel       matlab.ui.control.Label
        PTSDCheckBox                   matlab.ui.control.CheckBox
        SWTTEOCheckBox                 matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            [details_folder]= selectfolder('Select Group of Experiments'); % SpyCode_Function
            
            if strcmp(num2str(details_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            ls(details_folder)
            disp(ls(details_folder))
        end

        % Button pushed function: SelectGroupButton
        function SelectGroupButtonPushed(app, event)
            [start_folder]= selectfolder('Select Group of Experiments'); % SpyCode_Function
            
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            
            [path, exp_num, ext] = fileparts(start_folder);
            app.StartFolderLabel.Text = start_folder;
            app.StartfolderEditField.Value = start_folder;
            app.ExperimentsEditField.Value = exp_num;
            app.SelectGroupButton.Enable = false;
            app.SelectFolderButton.Enable = true;
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            app.bin_sizesecEditField.Editable = true;
            app.binsecEditField.Editable = true;
            app.Duration_WindowminEditField.Editable = true;
            app.NumofIterationsEditField.Editable = true;
        end

        % Value changed function: SelectAllCheckBox
        function SelectAllCheckBoxValueChanged(app, event)
            value = app.SelectAllCheckBox.Value;
            if value == true
                app.SpkCheckBox.Value = true;
                app.MfrCheckBox.Value = true;
                app.LvRCheckBox.Value = true;
                app.Boot_statsCheckBox.Value = true;
                app.PCCheckBox.Value = true;
                app.TSCheckBox.Value = true;
                app.SpkCheckBox.Enable = false;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
            else
                app.SpkCheckBox.Enable = true;
                app.MfrCheckBox.Enable = true;
                app.LvRCheckBox.Enable = true;
                app.Boot_statsCheckBox.Enable = true;
                app.PCCheckBox.Enable = true;
                app.TSCheckBox.Enable = true;
                app.SpkCheckBox.Value = false;
                app.MfrCheckBox.Value = false;
                app.LvRCheckBox.Value = false;
                app.Boot_statsCheckBox.Value = false;
                app.PCCheckBox.Value = false;
                app.TSCheckBox.Value = false;
            end
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % set Result Folder
            res_path = selectfolder('Select Experiment');
            Res_fold=(fullfile([res_path '/' app.ExperimentsEditField.Value '_Group']));
            app.ResultFolderEditField.Value = Res_fold;
            if exist(Res_fold,'dir')==0
                mkdir(Res_fold)
            end
            app.SelectFolderButton.Enable = false;
            app.ShowButton.Enable = false;
            app.StartButton.Enable = true;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            mfr=0;
            lvr=0;
            boot=0;
            pc=0;
            spk=0;
            %             if app.YesCheckBox.Value == true
            %                 YES=1;
            %             else
            %                 YES=0;
            %             end
            if app.SpkCheckBox.Value == true
                SPK=1;
            else
                SPK=0;
            end
            if app.MfrCheckBox.Value == true
                MFR=1;
            else
                MFR=0;
            end
            if app.LvRCheckBox.Value == true
                LVR=1;
            else
                LVR=0;
            end
            if app.Boot_statsCheckBox.Value == true
                BOOT=1;
            else
                BOOT=0;
            end
            if app.TSCheckBox.Value == true
                TS=1;
            else
                TS=0;
            end
            if app.PCCheckBox.Value == true
                PC=1;
            else
                PC=0;
            end
            if app.PTSDCheckBox.Value == true
                PTSD = 1;
                SWTTEO = 0;
            else
                PTSD = 0;
                SWTTEO = 1;
            end
            
            RASTER=0;
            RAW=0;
            
            if SPK==0 && TS==0 && PC==0 && BOOT==0 && LVR==0 && MFR==0 && app.SelectAllCheckBox.Value == false
                msgbox('Select Analysis','Allert','warn')
            else
                
                % Editable Fields False
                app.bin_sizesecEditField.Editable = false;
                app.binsecEditField.Editable = false;
                if app.Duration_WindowminEditField.Value == '-'
                    app.Duration_WindowminEditField.Value ='1';
                end
                app.Duration_WindowminEditField.Editable = false;
                app.NumofIterationsEditField.Editable = false;
                % Enable Check False
                app.SelectAllCheckBox.Enable = false;
                app.SpkCheckBox.Enable = false;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
                
                app.StartButton.Enable = 'off';
                
                % Control if already analyzed
                if app.Duration_WindowminEditField.Value == '1'
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentsEditField.Value '_DurationFixed_Original_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                else
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentsEditField.Value '_DurationFixed_' app.Duration_WindowminEditField.Value '_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                end
                if exist(res_duration,'dir')==0
                    mkdir(res_duration)
                end
                app.ResultsEditField.Value = res_duration;
                cd(app.ResultsEditField.Value)
                if exist(res_duration,'dir')==7
                    an_spk=(fullfile(res_duration,'/Analysis/','SPK.mat'));
                    K=0;
                    if exist(an_spk,'file') == 2 && SPK==1
                        app.Alert_SpkLabel.Text = 'K';
                        K=1;
                    end
                    an_mfr=(fullfile(res_duration,'/Analysis/',[app.ExperimentsEditField.Value '_MFR_' app.Duration_WindowminEditField.Value '.mat']));
                    M=0;
                    if exist(an_mfr,'file') == 2 && MFR==1
                        app.Alert_mfrLabel.Text = 'M';
                        M=1;
                    end
                    an_lvr=(fullfile(res_duration,'/Analysis/',[app.ExperimentsEditField.Value '_LVR_' app.Duration_WindowminEditField.Value '.mat']));
                    L=0;
                    if exist(an_lvr,'file') == 2 && LVR==1
                        app.Alert_lvrLabel.Text = 'L';
                        L=1;
                    end
                    an_boot=(fullfile(res_duration,'/Analysis/',[app.ExperimentsEditField.Value '_FR_' app.Duration_WindowminEditField.Value '.mat']));
                    B=0;
                    if exist(an_boot,'file') == 2 && BOOT==1
                        app.Alert_bootLabel.Text = 'B';
                        B=1;
                    end
                    an_pc=(fullfile(res_duration,'/Analysis/',[app.ExperimentsEditField.Value '_PC_' app.Duration_WindowminEditField.Value '.mat']));
                    P=0;
                    if exist(an_pc,'file') == 2 && PC==1
                        app.Alert_pcLabel.Text = 'P';
                        P=1;
                    end
                    an_ts=(fullfile(res_duration,'/Analysis/',[app.ExperimentsEditField.Value '_TS_' app.Duration_WindowminEditField.Value '.mat']));
                    T=0;
                    if exist(an_ts,'file') == 2 && TS==1
                        app.Alert_tsLabel.Text = 'T';
                        T=1;
                    end
                    %                     if K == 1 || M == 1 || L == 1 || B == 1 || P == 1 || T == 1
                    %                         % Call dialog box with input values
                    %
                    %                     else
                    %
                    Group_analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,PTSD,SWTTEO);
                    %
                    %                     end
                    
                    delete(app)
                end
            end
        end

        % Value changed function: PTSDCheckBox
        function PTSDCheckBoxValueChanged(app, event)
            value = app.PTSDCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = true;
                app.SWTTEOCheckBox.Value = true;
                app.PTSDCheckBox.Enable = false;
                PTSD = 0;
                SWTTEO = 1;
            end
        end

        % Value changed function: SWTTEOCheckBox
        function SWTTEOCheckBoxValueChanged(app, event)
            value = app.SWTTEOCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = false;
                app.PTSDCheckBox.Enable = true;
                app.PTSDCheckBox.Value = true;
                PTSD = 1;
                SWTTEO = 0;
            end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisListBoxLabel = uilabel(app.UIFigure);
            app.AnalysisListBoxLabel.Text = 'Plot Analysis:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.SelectAllCheckBox = uicheckbox(app.UIFigure);
            app.SelectAllCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged, true);
            app.SelectAllCheckBox.Text = 'Select All';
            app.SelectAllCheckBox.Value = true;
            app.Alert_mfrLabel = uilabel(app.UIFigure);
            app.Alert_mfrLabel.Enable = 'off';
            app.Alert_mfrLabel.Visible = 'off';
            app.Alert_mfrLabel.Text = 'Alert_mfr';
            app.ExpDetailsLabel = uilabel(app.UIFigure);
            app.ExpDetailsLabel.Text = 'Exp Details';
            app.ShowButton = uibutton(app.UIFigure, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.Alert_tsLabel = uilabel(app.UIFigure);
            app.Alert_tsLabel.Enable = 'off';
            app.Alert_tsLabel.Visible = 'off';
            app.Alert_tsLabel.Text = 'Alert_ts';
            app.ExperimentsEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentsEditFieldLabel.Text = 'Experiments:';
            app.ExperimentsEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentsEditField.Editable = 'off';
            app.ExperimentsEditField.Enable = 'off';
            app.StartfolderEditField = uieditfield(app.UIFigure, 'text');
            app.StartfolderEditField.Editable = 'off';
            app.StartfolderEditField.Enable = 'off';
            app.StartfolderEditField.Visible = 'off';
            app.ResultFolderEditFieldLabel = uilabel(app.UIFigure);
            app.ResultFolderEditFieldLabel.Enable = 'off';
            app.ResultFolderEditFieldLabel.Visible = 'off';
            app.ResultFolderEditFieldLabel.Text = 'Result Folder';
            app.ResultFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ResultFolderEditField.Editable = 'off';
            app.ResultFolderEditField.Enable = 'off';
            app.ResultFolderEditField.Visible = 'off';
            app.SelectGroupButton = uibutton(app.UIFigure, 'push');
            app.SelectGroupButton.ButtonPushedFcn = createCallbackFcn(app, @SelectGroupButtonPushed, true);
            app.SelectGroupButton.Text = 'Select Group';
            app.GroupExperimentsLabel = uilabel(app.UIFigure);
            app.GroupExperimentsLabel.Text = 'Group Experiments';
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Enable = 'off';
            app.SelectFolderButton.Text = 'Select Folder';
            app.ResultsFolderLabel = uilabel(app.UIFigure);
            app.ResultsFolderLabel.Text = 'Results Folder:';
            app.MfrCheckBox = uicheckbox(app.UIFigure);
            app.MfrCheckBox.Enable = 'off';
            app.MfrCheckBox.Text = 'Mfr';
            app.MfrCheckBox.Value = true;
            app.LvRCheckBox = uicheckbox(app.UIFigure);
            app.LvRCheckBox.Enable = 'off';
            app.LvRCheckBox.Text = 'LvR';
            app.LvRCheckBox.Value = true;
            app.Boot_statsCheckBox = uicheckbox(app.UIFigure);
            app.Boot_statsCheckBox.Enable = 'off';
            app.Boot_statsCheckBox.Text = 'Boot_stats';
            app.Boot_statsCheckBox.Value = true;
            app.PCCheckBox = uicheckbox(app.UIFigure);
            app.PCCheckBox.Enable = 'off';
            app.PCCheckBox.Text = 'PC';
            app.PCCheckBox.Value = true;
            app.Result_folder_durationLabel = uilabel(app.UIFigure);
            app.Result_folder_durationLabel.Enable = 'off';
            app.Result_folder_durationLabel.Visible = 'off';
            app.Result_folder_durationLabel.Text = 'Result_folder_duration';
            app.Alert_lvrLabel = uilabel(app.UIFigure);
            app.Alert_lvrLabel.Enable = 'off';
            app.Alert_lvrLabel.Visible = 'off';
            app.Alert_lvrLabel.Text = 'Alert_lvr';
            app.Alert_bootLabel = uilabel(app.UIFigure);
            app.Alert_bootLabel.Enable = 'off';
            app.Alert_bootLabel.Visible = 'off';
            app.Alert_bootLabel.Text = 'Alert_boot';
            app.Alert_pcLabel = uilabel(app.UIFigure);
            app.Alert_pcLabel.Enable = 'off';
            app.Alert_pcLabel.Visible = 'off';
            app.Alert_pcLabel.Text = 'Alert_pc';
            app.TSCheckBox = uicheckbox(app.UIFigure);
            app.TSCheckBox.Enable = 'off';
            app.TSCheckBox.Text = 'TS';
            app.TSCheckBox.Value = true;
            app.Alert_tsLabel_2 = uilabel(app.UIFigure);
            app.Alert_tsLabel_2.Enable = 'off';
            app.Alert_tsLabel_2.Visible = 'off';
            app.Alert_tsLabel_2.Text = 'Alert_ts';
            app.SpkCheckBox = uicheckbox(app.UIFigure);
            app.SpkCheckBox.Enable = 'off';
            app.SpkCheckBox.Text = 'Spk';
            app.SpkCheckBox.Value = true;
            app.Alert_SpkLabel = uilabel(app.UIFigure);
            app.Alert_SpkLabel.Enable = 'off';
            app.Alert_SpkLabel.Visible = 'off';
            app.Alert_SpkLabel.Text = 'Alert_boot';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters:';
            app.bin_sizesecEditFieldLabel = uilabel(app.UIFigure);
            app.bin_sizesecEditFieldLabel.Text = 'bin_size [sec]';
            app.bin_sizesecEditField = uieditfield(app.UIFigure, 'numeric');
            app.bin_sizesecEditField.Editable = 'off';
            app.bin_sizesecEditField.Value = 60;
            app.binsecEditFieldLabel = uilabel(app.UIFigure);
            app.binsecEditFieldLabel.Text = 'bin [sec]';
            app.binsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.binsecEditField.Editable = 'off';
            app.binsecEditField.Value = 1;
            app.NumofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumofIterationsEditFieldLabel.Text = 'NumofIterations ';
            app.NumofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumofIterationsEditField.Editable = 'off';
            app.NumofIterationsEditField.Value = 1000;
            app.Duration_WindowminEditFieldLabel = uilabel(app.UIFigure);
            app.Duration_WindowminEditFieldLabel.Text = 'Duration_Window [min]';
            app.Duration_WindowminEditField = uieditfield(app.UIFigure, 'text');
            app.Duration_WindowminEditField.Editable = 'off';
            app.Duration_WindowminEditField.Value = '-';
            app.ResultsEditFieldLabel = uilabel(app.UIFigure);
            app.ResultsEditFieldLabel.Text = 'Results:';
            app.ResultsEditField = uieditfield(app.UIFigure, 'text');
            app.ResultsEditField.Editable = 'off';
            app.ResultsEditField.Enable = 'off';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.StartFolderLabel = uilabel(app.UIFigure);
            app.StartFolderLabel.Enable = 'off';
            app.StartFolderLabel.Visible = 'off';
            app.StartFolderLabel.Text = 'StartFolder';
            app.WhichSpikeDetectionLabel = uilabel(app.UIFigure);
            app.WhichSpikeDetectionLabel.Text = 'Which Spike Detection?';
            app.PTSDCheckBox = uicheckbox(app.UIFigure);
            app.PTSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTSDCheckBoxValueChanged, true);
            app.PTSDCheckBox.Text = 'PTSD';
            app.PTSDCheckBox.Value = true;
            app.SWTTEOCheckBox = uicheckbox(app.UIFigure);
            app.SWTTEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @SWTTEOCheckBoxValueChanged, true);
            app.SWTTEOCheckBox.Enable = 'off';
            app.SWTTEOCheckBox.Text = 'SWTTEO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group_selection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end