classdef Plot_panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpAn_Plot_panel              matlab.ui.Figure
        AnalysisListBoxLabel         matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        QuitButton                   matlab.ui.control.Button
        ParametersLabel              matlab.ui.control.Label
        MfrCheckBox                  matlab.ui.control.CheckBox
        LvRCheckBox                  matlab.ui.control.CheckBox
        Boot_statsCheckBox           matlab.ui.control.CheckBox
        PCCheckBox                   matlab.ui.control.CheckBox
        Filtered_dataCheckBox        matlab.ui.control.CheckBox
        Raster_plotCheckBox          matlab.ui.control.CheckBox
        DoesntneedpreviousanalysisLabel  matlab.ui.control.Label
        NeedspreviousanalysisLabel   matlab.ui.control.Label
        SelectAllCheckBox_2          matlab.ui.control.CheckBox
        bin_sizesecEditFieldLabel    matlab.ui.control.Label
        bin_sizesecEditField         matlab.ui.control.NumericEditField
        binsecEditFieldLabel         matlab.ui.control.Label
        binsecEditField              matlab.ui.control.NumericEditField
        NumofIterationsLabel         matlab.ui.control.Label
        NumofIterationsEditField     matlab.ui.control.NumericEditField
        ExperimentEditFieldLabel     matlab.ui.control.Label
        ExperimentEditField          matlab.ui.control.EditField
        StartfolderEditFieldLabel    matlab.ui.control.Label
        StartfolderEditField         matlab.ui.control.EditField
        ResultFolderEditFieldLabel   matlab.ui.control.Label
        ResultFolderEditField        matlab.ui.control.EditField
        Duration_WindowminEditFieldLabel  matlab.ui.control.Label
        Duration_WindowminEditField  matlab.ui.control.EditField
        TSCheckBox                   matlab.ui.control.CheckBox
        Alert_RawLabel               matlab.ui.control.Label
        Alert_RasterLabel            matlab.ui.control.Label
        Alert_mfrLabel               matlab.ui.control.Label
        Alert_lvrLabel               matlab.ui.control.Label
        Alert_bootLabel              matlab.ui.control.Label
        Alert_tsLabel                matlab.ui.control.Label
        Alert_pcLabel                matlab.ui.control.Label
        Alert_an_mfrLabel            matlab.ui.control.Label
        Alert_an_lvrLabel            matlab.ui.control.Label
        Alert_an_bootLabel           matlab.ui.control.Label
        Alert_an_tsLabel             matlab.ui.control.Label
        Alert_an_pcLabel             matlab.ui.control.Label
        SortedLabel                  matlab.ui.control.Label
        YesCheckBox                  matlab.ui.control.CheckBox
        NoCheckBox                   matlab.ui.control.CheckBox
        Alert_YES_SortedLabel        matlab.ui.control.Label
        Alert_NO_SortedLabel         matlab.ui.control.Label
        SixteenChCheckBox            matlab.ui.control.CheckBox
        NeedspikedetectionLabel      matlab.ui.control.Label
        Spikes_plotCheckBox          matlab.ui.control.CheckBox
        Alert_SpkLabel               matlab.ui.control.Label
        ThirtytwoChCheckBox          matlab.ui.control.CheckBox
        WhichSpikeDetectionLabel     matlab.ui.control.Label
        PTSDCheckBox                 matlab.ui.control.CheckBox
        SWTTEOCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        CallingApp_Plot % Main app object
        DialogApp_plot_alert % Description
        DialogApp_plot_analysis_alert
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Single_Exp_Plot, exp, res_folder, start_folder)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp_Plot = Single_Exp_Plot;
            
            % Update UI with input values
            app.ExperimentEditField.Value = exp;
            app.StartfolderEditField.Value = start_folder;
            app.ResultFolderEditField.Value = res_folder;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.YesCheckBox.Value == true
                YES=1;
            else
                YES=0;
            end
            if app.Filtered_dataCheckBox.Value == true
                RAW=1;
            else
                RAW=0;
            end
            if app.Spikes_plotCheckBox.Value == true
                SPK=1;
                spk=1;
            else
                SPK=0;
                spk=0;
            end
            if app.Raster_plotCheckBox.Value == true
                RASTER=1;
            else
                RASTER=0;
            end
            if app.MfrCheckBox.Value == true
                MFR=1;
                mfr=1;
            else
                MFR=0;
                mfr=0;
            end
            if app.LvRCheckBox.Value == true
                LVR=1;
                lvr=1;
            else
                LVR=0;
                lvr=0;
            end
            if app.Boot_statsCheckBox.Value == true
                BOOT=1;
                boot=1;
            else
                BOOT=0;
                boot=0;
            end
            if app.PCCheckBox.Value == true
                PC=1;
                pc=1;
            else
                PC=0;
                pc=0;
            end
            if app.TSCheckBox.Value == true
                TS=1;
            else
                TS=0;
            end
            if app.SixteenChCheckBox.Value == true
                CH=16;
            elseif app.ThirtytwoChCheckBox.Value == true
                CH=32;
            else
                CH=0;
            end
            if app.PTSDCheckBox.Value == true
                PTSD = 1;
                SWTTEO = 0;
            else
                PTSD = 0;
                SWTTEO = 1;
            end
            
            if RAW==0 && SPK==0 && RASTER==0 && PC==0 && BOOT==0 && LVR==0 && MFR==0 && TS==0 && app.SelectAllCheckBox_2.Value==false
                msgbox('Select Plot','Allert','warn')
            else
                
                
                % Editable Fields False
                app.bin_sizesecEditField.Editable = false;
                app.binsecEditField.Editable = false;
                if app.Duration_WindowminEditField.Value == '-'
                    app.Duration_WindowminEditField.Value ='1';
                end
                app.Duration_WindowminEditField.Editable = false;
                app.NumofIterationsEditField.Editable = false;
                % Enable Check False
                app.SelectAllCheckBox_2.Enable = false;
                app.Filtered_dataCheckBox.Enable = false;
                app.SixteenChCheckBox.Enable = false;
                app.ThirtytwoChCheckBox.Enable = false;
                app.Spikes_plotCheckBox.Enable = false;
                app.Raster_plotCheckBox.Enable = false;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
                
                % app.StartButton.Enable = false;
                app.StartButton.Enable = 'off';
                
                % Control if already analyzed
                if app.Duration_WindowminEditField.Value == '1'
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentEditField.Value '_DurationFixed_Original_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                else
                    res_duration=(fullfile(app.ResultFolderEditField.Value,'/',[app.ExperimentEditField.Value '_DurationFixed_' app.Duration_WindowminEditField.Value '_binSize_' num2str(app.bin_sizesecEditField.Value) '_bin_' num2str(app.binsecEditField.Value) '_NoI_' num2str(app.NumofIterationsEditField.Value)]));
                end
                if exist(res_duration,'dir')==0
                    mkdir(res_duration)
                end
                app.ResultFolderEditField.Value = res_duration;
                cd(app.ResultFolderEditField.Value)
                if exist(res_duration,'dir')==7
                    % Plot
                    an_raw=(fullfile(res_duration,'/Plot/Raw/Filtered'));
                    W=0;
                    if exist(an_raw,'dir') == 7 && RAW==1
                        app.Alert_RawLabel.Text = 'W';
                        W=1;
                    end
                    an_spks=(fullfile(res_duration,'/Plot/Raw/Spikes'));
                    K=0;
                    if exist(an_spks,'dir') == 7 && SPK==1
                        app.Alert_SpkLabel.Text = 'K';
                        K=1;
                    end
                    an_raster=(fullfile(res_duration,'/Plot/Raster'));
                    R=0;
                    if exist(an_raster,'dir') == 7 && RASTER==1
                        app.Alert_RasterLabel.Text = 'R';
                        R=1;
                    end
                    an_mfr=(fullfile(res_duration,'/Plot/MFR'));
                    M=0;
                    if exist(an_mfr,'dir') == 7 && MFR==1
                        app.Alert_mfrLabel.Text = 'M';
                        M=1;
                    end
                    an_lvr=(fullfile(res_duration,'/Plot/LVR'));
                    L=0;
                    if exist(an_lvr,'dir') == 7 && LVR==1
                        app.Alert_lvrLabel.Text = 'L';
                        L=1;
                    end
                    an_boot=(fullfile(res_duration,'/Plot/FR'));
                    B=0;
                    if exist(an_boot,'dir') == 7 && BOOT==1
                        app.Alert_bootLabel.Text = 'B';
                        B=1;
                    end
                    an_pc=(fullfile(res_duration,'/Plot/PC'));
                    P=0;
                    if exist(an_pc,'dir') == 7 && PC==1
                        app.Alert_pcLabel.Text = 'P';
                        P=1;
                    end
                    an_ts=(fullfile(res_duration,'/Plot/TS'));
                    T=0;
                    if exist(an_ts,'dir') == 7 && TS==1
                        app.Alert_tsLabel.Text = 'T';
                        T=1;
                    end
                    % Analysis
                    if app.Duration_WindowminEditField.Value == '1'
                        an_plot_mfr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_MFR_Original.mat']));
                    else
                        an_plot_mfr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_MFR_' app.Duration_WindowminEditField.Value '.mat']));
                    end
                    F=0;
                    if exist(an_plot_mfr,'file') == 2 && MFR==1
                        app.Alert_an_mfrLabel.Text = 'F';
                        F=1;
                    end
                    % Analysis
                    if app.Duration_WindowminEditField.Value == '1'
                        an_plot_lvr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_LVR_Original.mat']));
                    else
                        an_plot_lvr=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_LVR_' app.Duration_WindowminEditField.Value '.mat']));
                    end
                    V=0;
                    if exist(an_plot_lvr,'file') == 2 && LVR==1
                        app.Alert_an_lvrLabel.Text = 'V';
                        V=1;
                    end
                    % Analysis
                    if app.Duration_WindowminEditField.Value == '1'
                        an_plot_boot=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_FR_Original.mat']));
                    else
                        an_plot_boot=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_FR_' app.Duration_WindowminEditField.Value '.mat']));
                    end
                    O=0;
                    if exist(an_plot_boot,'file') == 2 && BOOT==1
                        app.Alert_an_bootLabel.Text = 'O';
                        O=1;
                    end
                    % Analysis
                    if app.Duration_WindowminEditField.Value == '1'
                        an_plot_pc=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_PC_Original.mat']));
                    else
                        an_plot_pc=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_PC_' app.Duration_WindowminEditField.Value '.mat']));
                    end
                    C=0;
                    if exist(an_plot_pc,'file') == 2 && PC==1
                        app.Alert_an_pcLabel.Text = 'C';
                        C=1;
                    end
                    an_plot_ts=(fullfile(res_duration,'/Analysis/',[app.ExperimentEditField.Value '_TS_' app.Duration_WindowminEditField.Value '.mat']));
                    S=0;
                    if exist(an_plot_ts,'file') == 2 && TS==1
                        app.Alert_an_tsLabel.Text = 'S';
                        S=1;
                    end
                    
                    if K==1 || M == 1 || L == 1 || B == 1 || P == 1 || T == 1|| F == 1 || V == 1 || O == 1 || C == 1 || S == 1
                        % Call dialog box with input values for analysis
                        app.DialogApp_plot_analysis_alert = Analysis_Plot_alert(app,app.Alert_SpkLabel.Text,app.Alert_an_mfrLabel.Text,app.Alert_an_lvrLabel.Text,app.Alert_an_bootLabel.Text,app.Alert_an_pcLabel.Text,app.Alert_an_tsLabel.Text,app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO,CH);
                    elseif R == 1 || W == 1 || K ==1 % Rester plot, Filtered data or Spikes already done
                        app.DialogApp_plot_alert = Plot_alert(app,app.Alert_RawLabel.Text,app.Alert_SpkLabel.Text,app.Alert_RasterLabel.Text,app.Alert_an_mfrLabel.Text,app.Alert_an_lvrLabel.Text,app.Alert_an_bootLabel.Text,app.Alert_an_pcLabel.Text,app.Alert_an_tsLabel.Text,app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO,CH);
                    else
                        Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO,CH);
                    end
                else
                    Main_Analysis(app.StartfolderEditField.Value,app.ResultFolderEditField.Value,app.ExperimentEditField.Value,app.bin_sizesecEditField.Value,app.binsecEditField.Value,app.NumofIterationsEditField.Value,app.Duration_WindowminEditField.Value,MFR,LVR,BOOT,PC,TS,RASTER,RAW,SPK,spk,mfr,lvr,boot,pc,YES,PTSD,SWTTEO,CH);
                end
                delete(app)
            end
        end

        % Close request function: SpAn_Plot_panel
        function SpAn_Plot_panelCloseRequest(app, event)
            delete(app.DialogApp_plot_alert)
            delete(app.DialogApp_plot_analysis_alert)
            delete(app)
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: SelectAllCheckBox_2
        function SelectAllCheckBox_2ValueChanged(app, event)
            value = app.SelectAllCheckBox_2.Value;
            if value == true
                app.MfrCheckBox.Value = true;
                app.LvRCheckBox.Value = true;
                app.Boot_statsCheckBox.Value = true;
                app.PCCheckBox.Value = true;
                app.TSCheckBox.Value = true;
                app.MfrCheckBox.Enable = false;
                app.LvRCheckBox.Enable = false;
                app.Boot_statsCheckBox.Enable = false;
                app.PCCheckBox.Enable = false;
                app.TSCheckBox.Enable = false;
            else
                app.MfrCheckBox.Enable = true;
                app.LvRCheckBox.Enable = true;
                app.Boot_statsCheckBox.Enable = true;
                app.PCCheckBox.Enable = true;
                app.TSCheckBox.Enable = true;
                app.MfrCheckBox.Value = false;
                app.LvRCheckBox.Value = false;
                app.Boot_statsCheckBox.Value = false;
                app.PCCheckBox.Value = false;
                app.TSCheckBox.Value = false;
            end
        end

        % Value changed function: YesCheckBox
        function YesCheckBoxValueChanged(app, event)
            value = app.YesCheckBox.Value;
            if value == true
                app.NoCheckBox.Value = false;
                app.NoCheckBox.Enable = false;
            else
                app.NoCheckBox.Value = true;
                app.NoCheckBox.Enable = true;
                app.YesCheckBox.Enable = false;
            end
        end

        % Value changed function: Filtered_dataCheckBox, 
        % SixteenChCheckBox, ThirtytwoChCheckBox
        function Filtered_dataCheckBoxValueChanged(app, event)
            value = app.Filtered_dataCheckBox.Value;
            value2 = app.ThirtytwoChCheckBox.Value;
            value3 = app.SixteenChCheckBox.Value;
            
            %Number of Channels
            if value == true
                app.SixteenChCheckBox.Enable = true;
                app.ThirtytwoChCheckBox.Enable = true;
                if value2 == true
                    app.SixteenChCheckBox.Enable = false;
                elseif value3 == true
                    app.ThirtytwoChCheckBox.Enable = false;
                end
            end
        end

        % Value changed function: PTSDCheckBox
        function PTSDCheckBoxValueChanged(app, event)
            value = app.PTSDCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = true;
                app.SWTTEOCheckBox.Value = true;
                app.PTSDCheckBox.Enable = false;
                PTSD = 0;
                SWTTEO = 1;
            end
        end

        % Value changed function: SWTTEOCheckBox
        function SWTTEOCheckBoxValueChanged(app, event)
            value = app.SWTTEOCheckBox.Value;
            if value == false
                app.SWTTEOCheckBox.Enable = false;
                app.PTSDCheckBox.Enable = true;
                app.PTSDCheckBox.Value = true;
                PTSD = 1;
                SWTTEO = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpAn_Plot_panel = uifigure('Visible', 'off');
            app.SpAn_Plot_panel.Name = 'Span_Plot_panel';
            app.SpAn_Plot_panel.CloseRequestFcn = createCallbackFcn(app, @SpAn_Plot_panelCloseRequest, true);
            app.AnalysisListBoxLabel = uilabel(app.SpAn_Plot_panel);
            app.AnalysisListBoxLabel.Text = 'Plot:';
            app.StartButton = uibutton(app.SpAn_Plot_panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.SpAn_Plot_panel, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ParametersLabel = uilabel(app.SpAn_Plot_panel);
            app.ParametersLabel.Text = 'Parameters:';
            app.MfrCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.MfrCheckBox.Enable = 'off';
            app.MfrCheckBox.Text = 'Mfr';
            app.MfrCheckBox.Value = true;
            app.LvRCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.LvRCheckBox.Enable = 'off';
            app.LvRCheckBox.Text = 'LvR';
            app.LvRCheckBox.Value = true;
            app.Boot_statsCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.Boot_statsCheckBox.Enable = 'off';
            app.Boot_statsCheckBox.Text = 'Boot_stats';
            app.Boot_statsCheckBox.Value = true;
            app.PCCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.PCCheckBox.Enable = 'off';
            app.PCCheckBox.Text = 'PC';
            app.PCCheckBox.Value = true;
            app.Filtered_dataCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.Filtered_dataCheckBox.ValueChangedFcn = createCallbackFcn(app, @Filtered_dataCheckBoxValueChanged, true);
            app.Filtered_dataCheckBox.Text = 'Filtered_data';
            app.Raster_plotCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.Raster_plotCheckBox.Text = 'Raster_plot';
            app.DoesntneedpreviousanalysisLabel = uilabel(app.SpAn_Plot_panel);
            app.DoesntneedpreviousanalysisLabel.Text = 'Doesn''t need previous analysis:';
            app.NeedspreviousanalysisLabel = uilabel(app.SpAn_Plot_panel);
            app.NeedspreviousanalysisLabel.Text = 'Needs previous analysis:';
            app.SelectAllCheckBox_2 = uicheckbox(app.SpAn_Plot_panel);
            app.SelectAllCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBox_2ValueChanged, true);
            app.SelectAllCheckBox_2.Text = 'Select All';
            app.SelectAllCheckBox_2.Value = true;
            app.bin_sizesecEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.bin_sizesecEditFieldLabel.Text = 'bin_size [sec]';
            app.bin_sizesecEditField = uieditfield(app.SpAn_Plot_panel, 'numeric');
            app.bin_sizesecEditField.Value = 60;
            app.binsecEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.binsecEditFieldLabel.Text = 'bin [sec]';
            app.binsecEditField = uieditfield(app.SpAn_Plot_panel, 'numeric');
            app.binsecEditField.Value = 1;
            app.NumofIterationsLabel = uilabel(app.SpAn_Plot_panel);
            app.NumofIterationsLabel.Text = 'NumofIterations ';
            app.NumofIterationsEditField = uieditfield(app.SpAn_Plot_panel, 'numeric');
            app.NumofIterationsEditField.Value = 1000;
            app.ExperimentEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.ExperimentEditFieldLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.SpAn_Plot_panel, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.ExperimentEditField.Enable = 'off';
            app.StartfolderEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.StartfolderEditFieldLabel.Enable = 'off';
            app.StartfolderEditFieldLabel.Visible = 'off';
            app.StartfolderEditFieldLabel.Text = 'Start folder';
            app.StartfolderEditField = uieditfield(app.SpAn_Plot_panel, 'text');
            app.StartfolderEditField.Editable = 'off';
            app.StartfolderEditField.Enable = 'off';
            app.StartfolderEditField.Visible = 'off';
            app.ResultFolderEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.ResultFolderEditFieldLabel.Enable = 'off';
            app.ResultFolderEditFieldLabel.Visible = 'off';
            app.ResultFolderEditFieldLabel.Text = 'Result Folder';
            app.ResultFolderEditField = uieditfield(app.SpAn_Plot_panel, 'text');
            app.ResultFolderEditField.Editable = 'off';
            app.ResultFolderEditField.Enable = 'off';
            app.ResultFolderEditField.Visible = 'off';
            app.Duration_WindowminEditFieldLabel = uilabel(app.SpAn_Plot_panel);
            app.Duration_WindowminEditFieldLabel.Text = 'Duration_Window [min]';
            app.Duration_WindowminEditField = uieditfield(app.SpAn_Plot_panel, 'text');
            app.Duration_WindowminEditField.Value = '-';
            app.TSCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.TSCheckBox.Enable = 'off';
            app.TSCheckBox.Text = 'TS';
            app.TSCheckBox.Value = true;
            app.Alert_RawLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_RawLabel.Enable = 'off';
            app.Alert_RawLabel.Visible = 'off';
            app.Alert_RawLabel.Text = 'Alert_Raw';
            app.Alert_RasterLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_RasterLabel.Enable = 'off';
            app.Alert_RasterLabel.Visible = 'off';
            app.Alert_RasterLabel.Text = 'Alert_Raster';
            app.Alert_mfrLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_mfrLabel.Enable = 'off';
            app.Alert_mfrLabel.Visible = 'off';
            app.Alert_mfrLabel.Text = 'Alert_mfr';
            app.Alert_lvrLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_lvrLabel.Enable = 'off';
            app.Alert_lvrLabel.Visible = 'off';
            app.Alert_lvrLabel.Text = 'Alert_lvr';
            app.Alert_bootLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_bootLabel.Enable = 'off';
            app.Alert_bootLabel.Visible = 'off';
            app.Alert_bootLabel.Text = 'Alert_boot';
            app.Alert_tsLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_tsLabel.Enable = 'off';
            app.Alert_tsLabel.Visible = 'off';
            app.Alert_tsLabel.Text = 'Alert_ts';
            app.Alert_pcLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_pcLabel.Enable = 'off';
            app.Alert_pcLabel.Visible = 'off';
            app.Alert_pcLabel.Text = 'Alert_pc';
            app.Alert_an_mfrLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_an_mfrLabel.Enable = 'off';
            app.Alert_an_mfrLabel.Visible = 'off';
            app.Alert_an_mfrLabel.Text = 'Alert_an_mfr';
            app.Alert_an_lvrLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_an_lvrLabel.Enable = 'off';
            app.Alert_an_lvrLabel.Visible = 'off';
            app.Alert_an_lvrLabel.Text = 'Alert_an_lvr';
            app.Alert_an_bootLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_an_bootLabel.Enable = 'off';
            app.Alert_an_bootLabel.Visible = 'off';
            app.Alert_an_bootLabel.Text = 'Alert_an_boot';
            app.Alert_an_tsLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_an_tsLabel.Enable = 'off';
            app.Alert_an_tsLabel.Visible = 'off';
            app.Alert_an_tsLabel.Text = 'Alert_an_ts';
            app.Alert_an_pcLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_an_pcLabel.Enable = 'off';
            app.Alert_an_pcLabel.Visible = 'off';
            app.Alert_an_pcLabel.Text = 'Alert_an_pc';
            app.SortedLabel = uilabel(app.SpAn_Plot_panel);
            app.SortedLabel.Text = 'Sorted?';
            app.YesCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.YesCheckBox.ValueChangedFcn = createCallbackFcn(app, @YesCheckBoxValueChanged, true);
            app.YesCheckBox.Text = 'Yes';
            app.YesCheckBox.Value = true;
            app.NoCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.NoCheckBox.Enable = 'off';
            app.NoCheckBox.Text = 'No';
            app.Alert_YES_SortedLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_YES_SortedLabel.Enable = 'off';
            app.Alert_YES_SortedLabel.Visible = 'off';
            app.Alert_YES_SortedLabel.Text = 'Alert_YES_Sorted';
            app.Alert_NO_SortedLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_NO_SortedLabel.Enable = 'off';
            app.Alert_NO_SortedLabel.Visible = 'off';
            app.Alert_NO_SortedLabel.Text = 'Alert_NO_Sorted';
            app.SixteenChCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.SixteenChCheckBox.ValueChangedFcn = createCallbackFcn(app, @Filtered_dataCheckBoxValueChanged, true);
            app.SixteenChCheckBox.Enable = 'off';
            app.SixteenChCheckBox.Text = '16-Ch';
            app.NeedspikedetectionLabel = uilabel(app.SpAn_Plot_panel);
            app.NeedspikedetectionLabel.Text = 'Need spike detection:';
            app.Spikes_plotCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.Spikes_plotCheckBox.Text = 'Spikes_plot';
            app.Alert_SpkLabel = uilabel(app.SpAn_Plot_panel);
            app.Alert_SpkLabel.Enable = 'off';
            app.Alert_SpkLabel.Visible = 'off';
            app.Alert_SpkLabel.Text = 'Alert_Spk';
            app.ThirtytwoChCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.ThirtytwoChCheckBox.ValueChangedFcn = createCallbackFcn(app, @Filtered_dataCheckBoxValueChanged, true);
            app.ThirtytwoChCheckBox.Enable = 'off';
            app.ThirtytwoChCheckBox.Text = '32-Ch';
            app.WhichSpikeDetectionLabel = uilabel(app.SpAn_Plot_panel);
            app.WhichSpikeDetectionLabel.Text = 'Which Spike Detection?';
            app.PTSDCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.PTSDCheckBox.ValueChangedFcn = createCallbackFcn(app, @PTSDCheckBoxValueChanged, true);
            app.PTSDCheckBox.Text = 'PTSD';
            app.PTSDCheckBox.Value = true;
            app.SWTTEOCheckBox = uicheckbox(app.SpAn_Plot_panel);
            app.SWTTEOCheckBox.ValueChangedFcn = createCallbackFcn(app, @SWTTEOCheckBoxValueChanged, true);
            app.SWTTEOCheckBox.Enable = 'off';
            app.SWTTEOCheckBox.Text = 'SWTTEO';
            % Show the figure after all components are created
            app.SpAn_Plot_panel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_panel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpAn_Plot_panel)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpAn_Plot_panel)
        end
    end
end