classdef Single_Exp_Plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Plot                       matlab.ui.Figure
        SelectOneButton            matlab.ui.control.Button
        SingleExperimentLabel      matlab.ui.control.Label
        SelectPlotButton           matlab.ui.control.Button
        QuitButton                 matlab.ui.control.Button
        ResultsLabel               matlab.ui.control.Label
        SelectFolderButton         matlab.ui.control.Button
        StartfolderLabel           matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
        ShowButton                 matlab.ui.control.Button
        ExperimentsDetailsLabel    matlab.ui.control.Label
        ExperimentEditFieldLabel   matlab.ui.control.Label
        ExperimentEditField        matlab.ui.control.EditField
        ResultFolderTextAreaLabel  matlab.ui.control.Label
        ResultFolderTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        DialogApp_Plot % Dialog box app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectPlotButton
        function SelectPlotButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SelectPlotButton.Enable = 'off';
            % Open the options dialog and pass inputs
            app.DialogApp_Plot = Plot_panel(app, app.ExperimentEditField.Value, app.ResultLabel.Text, app.StartfolderLabel.Text);
            delete(app)
        end

        % Close request function: Plot
        function PlotCloseRequest(app, event)
            delete(app.DialogApp_Plot)
            delete(app)
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            [details_folder]= selectfolder('Select Experiment'); % SpyCode_Function
            
            if strcmp(num2str(details_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            
            f = waitbar(0,'Please wait...');
            pause(.5)
            load(fullfile([details_folder '_Tank.mat']))
            tankObj.nigelDash
            close(f)
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % set Result Folder
            res_path = selectfolder('Select Experiment');
            Res_fold=(fullfile([res_path '/' app.ExperimentEditField.Value '_STATS']));
            app.ResultFolderTextArea.Value = Res_fold;
            app.ResultLabel.Text = Res_fold;
            if exist(Res_fold,'dir')==0
                mkdir(Res_fold)
            end
            app.SelectFolderButton.Enable = false;
            app.ShowButton.Enable = false;
            app.SelectPlotButton.Enable = true;
        end

        % Button pushed function: SelectOneButton
        function SelectOneButtonPushed(app, event)
            [start_folder]= selectfolder('Select Experiment'); % SpyCode_Function
            
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            
            [path, exp_num, ext] = fileparts(start_folder);
            app.StartfolderLabel.Text = start_folder;
            app.ExperimentEditField.Value = exp_num;
            app.SelectOneButton.Enable = false;
            app.SelectFolderButton.Enable = true;            
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Plot = uifigure('Visible', 'off');
            app.Plot.Name = 'UI Plot';
            app.Plot.CloseRequestFcn = createCallbackFcn(app, @PlotCloseRequest, true);
            app.SelectOneButton = uibutton(app.Plot, 'push');
            app.SelectOneButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOneButtonPushed, true);
            app.SelectOneButton.Text = 'Select One';
            app.SingleExperimentLabel = uilabel(app.Plot);
            app.SingleExperimentLabel.Text = 'Single Experiment';
            app.SelectPlotButton = uibutton(app.Plot, 'push');
            app.SelectPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotButtonPushed, true);
            app.SelectPlotButton.Enable = 'off';
            app.SelectPlotButton.Text = 'Select Plot';
            app.QuitButton = uibutton(app.Plot, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.ResultsLabel = uilabel(app.Plot);
            app.ResultsLabel.Text = 'Results ';
            app.SelectFolderButton = uibutton(app.Plot, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Enable = 'off';
            app.SelectFolderButton.Text = 'Select Folder';
            app.StartfolderLabel = uilabel(app.Plot);
            app.StartfolderLabel.Enable = 'off';
            app.StartfolderLabel.Visible = 'off';
            app.StartfolderLabel.Text = 'Start folder';
            app.ResultLabel = uilabel(app.Plot);
            app.ResultLabel.Enable = 'off';
            app.ResultLabel.Visible = 'off';
            app.ResultLabel.Text = 'ResultLabel';
            app.ShowButton = uibutton(app.Plot, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ExperimentsDetailsLabel = uilabel(app.Plot);
            app.ExperimentsDetailsLabel.Text = 'Experiments Details';
            app.ExperimentEditFieldLabel = uilabel(app.Plot);
            app.ExperimentEditFieldLabel.Text = 'Experiment:';
            app.ExperimentEditField = uieditfield(app.Plot, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.ResultFolderTextAreaLabel = uilabel(app.Plot);
            app.ResultFolderTextAreaLabel.Text = 'Result Folder:';
            app.ResultFolderTextArea = uitextarea(app.Plot);
            app.ResultFolderTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.Plot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Single_Exp_Plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Plot)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Plot)
        end
    end
end