classdef SpAn_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpAnUIFigure                    matlab.ui.Figure
        SelectSystemMenu                matlab.ui.container.Menu
        macOSMenu                       matlab.ui.container.Menu
        InstallHomebrewMenu             matlab.ui.container.Menu
        Compilearmadillo_libMenu        matlab.ui.container.Menu
        WindowsMenu                     matlab.ui.container.Menu
        Compilearmadillo_libMenu_2      matlab.ui.container.Menu
        LinuxMenu                       matlab.ui.container.Menu
        ConvertRawDataMenu              matlab.ui.container.Menu
        rhsMenu                         matlab.ui.container.Menu
        SelectExperimentsMenu_Convert   matlab.ui.container.Menu
        OtherMenu_2                     matlab.ui.container.Menu
        PreProcessingMenu               matlab.ui.container.Menu
        IntansystemMenu                 matlab.ui.container.Menu
        SelectExperimentsMenu_PreProc   matlab.ui.container.Menu
        OtherMenu                       matlab.ui.container.Menu
        DataSplitMenu                   matlab.ui.container.Menu
        SelectExperimentMenu_DataSplit  matlab.ui.container.Menu
        BasalAnalysisMenu               matlab.ui.container.Menu
        PerformSingleAnalysisMenu       matlab.ui.container.Menu
        SelectExperimentMenu_SingleAn   matlab.ui.container.Menu
        PerformGroupAnalysisMenu        matlab.ui.container.Menu
        SelectExperimentsMenu_GroupAn   matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        SingleAnalysisMenu              matlab.ui.container.Menu
        SelectExperimentMenu_SinglePlot  matlab.ui.container.Menu
        GroupAnalysisMenu               matlab.ui.container.Menu
        SelectExperimentsMenu_GroupPlot  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        UserReferenceMenu               matlab.ui.container.Menu
        Aboutname_appMenu               matlab.ui.container.Menu
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: SelectExperimentMenu_DataSplit
        function InstallMenuSelected(app, event)
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            Data_split;
        end

        % Menu selected function: SelectExperimentsMenu_GroupAn
        function SelectExperimentsMenu_GroupAnSelected(app, event)
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            Group_selection;
        end

        % Menu selected function: SelectExperimentMenu_SingleAn
        function SelectExperimentMenu_SingleAnSelected(app, event)
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            Single_Exp_Analysis;
        end

        % Menu selected function: InstallHomebrewMenu
        function InstallHomebrewMenuSelected(app, event)
            
        end

        % Menu selected function: SelectSystemMenu
        function SelectSystemMenuSelected(app, event)
            
        end

        % Menu selected function: SelectExperimentsMenu_PreProc
        function SelectExperimentsMenu_PreProcSelected(app, event)
            mkdir('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa')
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            
            [start_folder]= selectfolder('Select Group of Experiments'); % SpyCode_Function
            
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            
            f = waitbar(0,'Please wait...');
            load(fullfile([start_folder '_Tank.mat']))
            tankObj.nigelDash
            close(f)

        end

        % Menu selected function: SelectExperimentMenu_SinglePlot
        function SelectExperimentMenu_SinglePlotSelected(app, event)
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            Single_Exp_Plot;
        end

        % Menu selected function: SelectExperimentsMenu_Convert
        function SelectExperimentsMenu_ConvertSelected(app, event)
            addpath(genpath('C:\Users\mcare\OneDrive - Fondazione Istituto Italiano Tecnologia\Code_MCa'))
            addpath('C:\Users\mcare\Documents\MATLAB\nigeLab')
            
            [start_folder]= selectfolder('Select Group of Experiments'); % SpyCode_Function
            
            if strcmp(num2str(start_folder),'0')
                errordlg('Selection Failed - End of Session', 'Error');
                return
            end
            tankObj=nigeLab.Tank;
        end

        % Menu selected function: rhsMenu
        function rhsMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpAnUIFigure = uifigure('Visible', 'off');
            app.SpAnUIFigure.Name = 'SpAn';
            app.SelectSystemMenu = uimenu(app.SpAnUIFigure);
            app.SelectSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectSystemMenuSelected, true);
            app.SelectSystemMenu.Text = 'Select System';
            app.macOSMenu = uimenu(app.SelectSystemMenu);
            app.macOSMenu.Text = 'macOS';
            app.InstallHomebrewMenu = uimenu(app.macOSMenu);
            app.InstallHomebrewMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallHomebrewMenuSelected, true);
            app.InstallHomebrewMenu.Text = 'Install Homebrew';
            app.Compilearmadillo_libMenu = uimenu(app.macOSMenu);
            app.Compilearmadillo_libMenu.Text = 'Compile armadillo_lib';
            app.WindowsMenu = uimenu(app.SelectSystemMenu);
            app.WindowsMenu.Text = 'Windows';
            app.Compilearmadillo_libMenu_2 = uimenu(app.WindowsMenu);
            app.Compilearmadillo_libMenu_2.Text = 'Compile armadillo_lib';
            app.LinuxMenu = uimenu(app.SelectSystemMenu);
            app.LinuxMenu.Text = 'Linux';
            app.ConvertRawDataMenu = uimenu(app.SpAnUIFigure);
            app.ConvertRawDataMenu.Text = 'Convert Raw Data';
            app.rhsMenu = uimenu(app.ConvertRawDataMenu);
            app.rhsMenu.MenuSelectedFcn = createCallbackFcn(app, @rhsMenuSelected, true);
            app.rhsMenu.Text = '.rhs';
            app.SelectExperimentsMenu_Convert = uimenu(app.rhsMenu);
            app.SelectExperimentsMenu_Convert.MenuSelectedFcn = createCallbackFcn(app, @SelectExperimentsMenu_ConvertSelected, true);
            app.SelectExperimentsMenu_Convert.Text = 'Select Group of Experiments';
            app.OtherMenu_2 = uimenu(app.ConvertRawDataMenu);
            app.OtherMenu_2.Text = 'Other';
            app.PreProcessingMenu = uimenu(app.SpAnUIFigure);
            app.PreProcessingMenu.Text = 'Pre Processing';
            app.IntansystemMenu = uimenu(app.PreProcessingMenu);
            app.IntansystemMenu.Text = 'Intan system';
            app.SelectExperimentsMenu_PreProc = uimenu(app.IntansystemMenu);
            app.SelectExperimentsMenu_PreProc.MenuSelectedFcn = createCallbackFcn(app, @SelectExperimentsMenu_PreProcSelected, true);
            app.SelectExperimentsMenu_PreProc.Text = 'Select Group of Experiments';
            app.OtherMenu = uimenu(app.PreProcessingMenu);
            app.OtherMenu.Text = 'Other';
            app.DataSplitMenu = uimenu(app.SpAnUIFigure);
            app.DataSplitMenu.Text = 'Data Split';
            app.SelectExperimentMenu_DataSplit = uimenu(app.DataSplitMenu);
            app.SelectExperimentMenu_DataSplit.MenuSelectedFcn = createCallbackFcn(app, @InstallMenuSelected, true);
            app.SelectExperimentMenu_DataSplit.Text = 'Select Experiment';
            app.BasalAnalysisMenu = uimenu(app.SpAnUIFigure);
            app.BasalAnalysisMenu.Text = 'Basal Analysis';
            app.PerformSingleAnalysisMenu = uimenu(app.BasalAnalysisMenu);
            app.PerformSingleAnalysisMenu.Text = 'Perform Single Analysis';
            app.SelectExperimentMenu_SingleAn = uimenu(app.PerformSingleAnalysisMenu);
            app.SelectExperimentMenu_SingleAn.MenuSelectedFcn = createCallbackFcn(app, @SelectExperimentMenu_SingleAnSelected, true);
            app.SelectExperimentMenu_SingleAn.Text = 'Select Experiment';
            app.PerformGroupAnalysisMenu = uimenu(app.BasalAnalysisMenu);
            app.PerformGroupAnalysisMenu.Text = 'Perform Group Analysis';
            app.SelectExperimentsMenu_GroupAn = uimenu(app.PerformGroupAnalysisMenu);
            app.SelectExperimentsMenu_GroupAn.MenuSelectedFcn = createCallbackFcn(app, @SelectExperimentsMenu_GroupAnSelected, true);
            app.SelectExperimentsMenu_GroupAn.Text = 'Select Group';
            app.PlotMenu = uimenu(app.SpAnUIFigure);
            app.PlotMenu.Text = 'Plot';
            app.SingleAnalysisMenu = uimenu(app.PlotMenu);
            app.SingleAnalysisMenu.Text = 'Single Analysis';
            app.SelectExperimentMenu_SinglePlot = uimenu(app.SingleAnalysisMenu);
            app.SelectExperimentMenu_SinglePlot.MenuSelectedFcn = createCallbackFcn(app, @SelectExperimentMenu_SinglePlotSelected, true);
            app.SelectExperimentMenu_SinglePlot.Text = 'Select Experiment';
            app.GroupAnalysisMenu = uimenu(app.PlotMenu);
            app.GroupAnalysisMenu.Text = 'Group Analysis';
            app.SelectExperimentsMenu_GroupPlot = uimenu(app.GroupAnalysisMenu);
            app.SelectExperimentsMenu_GroupPlot.Text = 'Select Experiments';
            app.HelpMenu = uimenu(app.SpAnUIFigure);
            app.HelpMenu.Text = 'Help';
            app.UserReferenceMenu = uimenu(app.HelpMenu);
            app.UserReferenceMenu.Text = 'User Reference';
            app.Aboutname_appMenu = uimenu(app.HelpMenu);
            app.Aboutname_appMenu.Text = 'About name_app';
            % Show the figure after all components are created
            app.SpAnUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpAn_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpAnUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpAnUIFigure)
        end
    end
end